classdef CellAnalysisApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ThresholdingOptionsButtonGroup  matlab.ui.container.ButtonGroup
        YesButton                       matlab.ui.control.RadioButton
        NoButton                        matlab.ui.control.RadioButton
        ChannelforAnalysisDropDown      matlab.ui.control.DropDown
        ChannelforAnalysisDropDownLabel  matlab.ui.control.Label
        SubmitButton                    matlab.ui.control.Button
        CellAnalysisLabel               matlab.ui.control.Label
    end

methods (Access = private)

        %intakes parameters necessary to accessing and processing
        %an image into a binarized version. returns location of
        %saved binary image.
        function [fullSaveA, fullSaveB, fileNameRemoved] = CellBinarize(~, yesVal, channelNum, selectedChannel, fileName, fileImportLocation, fileSaveLocation, fileEndingA, fileEndingB, mask, dimension)
                fileToOpen = strcat(fileImportLocation, filesep, fileName);

                ij.IJ.open(fileToOpen);

                if mask == 1
                    if strcmp(dimension, '3D') == 1
                        ij.IJ.run("To ROI Manager");
                    else
                        ij.IJ.runMacro("Roi.setPosition(1)");
                        ij.IJ.runMacro('roiManager("Add")');
                    end
                end

                if channelNum > 1
                    ij.IJ.run("Stack Slicer", "split_channels stack_order=XYCZT");
                    ij.IJ.selectWindow(strcat(fileName, ' - C=', string(selectedChannel)));
                end

                ij.IJ.run("Duplicate...", " ");
    
                possibleChannels = 0:1:channelNum - 1;
                exclude = selectedChannel;
                channels = setdiff(possibleChannels, exclude);

                for i=1:(channelNum - 1)
                    channelToClose = string(channels(i));
                    ij.IJ.runMacro(strcat("close('", fileName, ' - C=', channelToClose, "')"));
                end

                [~, fileNameRemoved, ~] = fileparts(fileName);
                newFileNameA = strcat(fileNameRemoved, fileEndingA, '_C_', string(selectedChannel));
                newFileNameB = strcat(fileNameRemoved, fileEndingB, '_C_', string(selectedChannel));

                fullSaveA = strcat(fileSaveLocation, filesep, newFileNameA);
                fullSaveB = strcat(fileSaveLocation, filesep, newFileNameB);

                ij.IJ.selectWindow(strcat(fileName, ' - C=', string(selectedChannel)));
    
                %converts selected channel to 32-bit and assigns areas
                %outside of mask as 0. this will then be imported to matlab
                %as a binary image.
                if mask == 1
                    ij.IJ.runMacro('roiManager("Select", 0)');
                    ij.IJ.runMacro('changeValues(0,255,0)');
                end

                if yesVal == 1
                    ij.IJ.run('Auto Local Threshold');
                else
                    ij.IJ.run("Auto Local Threshold", "method=Mean radius=30 parameter_1=-5 parameter_2=0 white"); %current default setting. possible presets in the future? tweaked base values?
                end

                ij.IJ.saveAs('Tiff', fullSaveA);

                ij.IJ.selectWindow(strcat(fileName, ' - C=', string(selectedChannel), '-1'));
    
                if mask == 1
                    ij.IJ.run("32-bit");
                    ij.IJ.runMacro('roiManager("Select", 0)');
                    ij.IJ.runMacro('changeValues(0,255,NaN)');
                    ij.IJ.runMacro('close("ROI Manager")');
                end
    
                ij.IJ.saveAs('Tiff', fullSaveB);
                ij.IJ.runMacro('close("*")');
        end

        %set up for converting from pixel to micron using image metadata. 
        %if X and Y resolutions do not agree, no conversion occurs due to
        %theoretical pixel mismatch.
        function [Unit, Value] = UnitConvert(~, image)
            imageInfo = imfinfo(image, 'tif');
            if round(imageInfo.XResolution, 4) == round(imageInfo.YResolution, 4)
                Unit = 'micron';
                Value = imageInfo.XResolution;
            else
                Unit = 'pixel';
                Value = 1;
            end
        end
end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
           
            %import "cube" to pull data from workspace
            batch = evalin('base', 'batch');
            fileNum = evalin('base', 'fileNum');
            channelNum = evalin('base', 'channelNum');
            dimension = evalin('base', 'dimension'); 
            fileImportLocation = evalin('base', 'fileImportLocation');
            fileSaveLocation = evalin('base', 'fileSaveLocation');
            mask = evalin('base', 'mask');

            %assigning other necessary parameters
            selectedChannel = str2double(app.ChannelforAnalysisDropDown.Value) - 1;
            yesVal = app.YesButton.Value;
            fileEnding = '_CellAnalysis';
            fileEndingA = '_CellAnalysisBinary';
            fileEndingB = '_CellAnalysisUnprocessed';

            %close ui window
            closereq();

            if strcmp(dimension, '3D') == 1
                msgbox("Cell Analysis is only avalible for 2D or cryosection images.","Error","error");
                MEMLQuantToolboxApp;
                return
            end

            resultsLocation = evalin('base', 'resultsLocation');
            results = fopen(resultsLocation,'a');
            timestamp = strjoin(string(fix(clock)),'_');
            header = strcat(timestamp, fileEnding, '_C_', string(selectedChannel));
            fprintf(results, '-------------%s-------------\n', header);
            fprintf(results, '%s, %s, %s, %s, %s, %s, %s, %s, %s, %s, %s, %s, %s, %s, %s, %s, %s, %s, \n', 'fileName', 'cellCount', 'cellDensity', 'unit', 'majorAxisMean', 'majorAxisStd', 'majorAxisCV', 'minorAxisMean', 'minorAxisStd', 'minorAxisCV', 'eccentricityMean', 'eccentricityStd', 'eccentricityCV', 'cellAreaMean', 'cellAreaStd', 'cellAreaCV', 'orientationMean', 'orientationStd');
                
            %create folder for raw data. created only when needed to avoid
            %clutter on sessions which do not store raw data.
            rawData = strcat(fileSaveLocation, filesep, 'Raw_Data');
            if ~exist(rawData, 'dir')
                mkdir(rawData);
            end

            %preping processing exclusive to single or batch images
            if batch == 1
                folder = dir(fileImportLocation);
                %https://stackoverflow.com/questions/27337514/matlab-dir-without-and
                %.DS_Store is MacOS exclusive and added by the OS to folders.
                %it must be filtered out for proper function.
                folder = folder(~ismember({folder.name},{'.','..','.DS_Store'}));
            else
                %only passed if single image is imported
                fileName = evalin('base', 'fileName');
            end

            for i=1:fileNum
                if batch == 1
                    fileName = folder(i).name;
                end

                [fullSaveA, fullSaveB, fileNameRemoved] = CellBinarize(app, yesVal, channelNum, selectedChannel, fileName, fileImportLocation, fileSaveLocation, fileEndingA, fileEndingB, mask, dimension);

                ImA = imread(fullSaveA,'tif');
                ImB = imread(fullSaveB, 'tif');

                Im1(:,:) = imbinarize(ImA);
                Im2(:,:) = im2double(ImB);
                Im3(:,:) = cast(ImB, 'uint8');

                assignin('base', 'processedIm', Im1);
                assignin('base', 'unprocessedIm', Im3);

                %note: what constitutes a blob is defined by pixel area.
                %this means that higher resolutions might have issues.
                out = sim('CellAnalysis.slx');

                imwrite(out.QA, strcat(fileSaveLocation, filesep, fileName, fileEnding, '.tif'));

                [pixelConvertUnit, pixelConvert] = UnitConvert(app, fullSaveB);

                %array info
                majorAxis = out.MajorAxis(1:out.Count) / pixelConvert;
                minorAxis = out.MinorAxis(1:out.Count) / pixelConvert;
                eccentricity = out.Eccentricity(1:out.Count);
                cellArea = out.Area(1:out.Count) / (pixelConvert^2);
                orientation = out.Orientation(1:out.Count);

                %assigning statistics
                majorAxisMean = mean(majorAxis);
                majorAxisStd = std(majorAxis);
                majorAxisCV = majorAxisStd/majorAxisMean;
                minorAxisMean = mean(minorAxis);
                minorAxisStd = std(minorAxis);
                minorAxisCV = minorAxisStd/minorAxisMean;
                eccentricityMean = mean(eccentricity);
                eccentricityStd = std(eccentricity);
                eccentricityCV = eccentricityStd/eccentricityMean;
                cellAreaMean = mean(cellArea);
                cellAreaStd = std(double(cellArea));
                cellAreaCV = cellAreaStd/cellAreaMean;
                orientationMean = mean(orientation);
                orientationStd = std(orientation);

                %single values
                cellCount = out.Count;

                area = numel(Im2(~isnan(Im2))) / (pixelConvert ^ 2);
                cellDensity = double(cellCount)/area;

                %creating and writing to raw data file
                matrix = string(cat(2, majorAxis, minorAxis, eccentricity, double(cellArea), orientation));
                headers = ["majorAxis" "minorAxis" "eccentricity" "cellArea" "orientation"];
                matrixHeaded = cat(1, headers, matrix); 
                writematrix(matrixHeaded, strcat(rawData, filesep, fileNameRemoved, '.csv'));

                %printing statistics to results file
                fprintf(results, '%s, %f, %f, %s, %f, %f, %f, %f, %f, %f, %f, %f, %f, %f, %f, %f, %f, %f \n', fileName, cellCount, cellDensity, pixelConvertUnit, majorAxisMean, majorAxisStd, majorAxisCV, minorAxisMean, minorAxisStd, minorAxisCV, eccentricityMean, eccentricityStd, eccentricityCV, cellAreaMean, cellAreaStd, cellAreaCV, orientationMean, orientationStd);
                ij.IJ.runMacro('close("*")');

                if batch == 0
                    uiwait(msgbox(sprintf('File Name: %s \nCell Count: %f \nCell Density: %f \nUnits: %s \nMajor Axis Mean: %f \nMajor Axis Standard Deviation: %f \nMinor Axis Mean: %f \nMinor Axis Standard Deviation: %f \nEccentricity Mean: %f \nEccentricity Standard Deviation: %f \nCell Area Mean: %f \n Cell Area Standard Deviation: %f \nOrientation Mean (radians): %f \nOrientation Standard Deviation: %f', fileName, cellCount, cellDensity, pixelConvertUnit, majorAxisMean, majorAxisStd, minorAxisMean, minorAxisStd, eccentricityMean, eccentricityStd, cellAreaMean, cellAreaStd, orientationMean, orientationStd)));
                    ij.IJ.runMacro('close("*")');
                end

                %clearing image matrices for reuse
                clear Im1;
                clear Im2;
                clear Im3;
                
                %deletes 'processed' and 'unprocessed' images
                delete(strcat(fullSaveA, '.tif'), strcat(fullSaveB, '.tif'));
            end

            %saving mechanism ???

            %deletion mechanism ??? 

            MEMLQuantToolboxApp;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CellAnalysisLabel = uilabel(app.UIFigure);
            app.CellAnalysisLabel.Text = 'Cell Analysis';
            app.SubmitButton = uibutton(app.UIFigure, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.ChannelforAnalysisDropDownLabel = uilabel(app.UIFigure);
            app.ChannelforAnalysisDropDownLabel.Text = 'Channel for Analysis';
            app.ChannelforAnalysisDropDown = uidropdown(app.UIFigure);
            app.ChannelforAnalysisDropDown.Items = {' ', '1', '2', '3', '4', '5', '6'};
            app.ChannelforAnalysisDropDown.Value = ' ';
            app.ThresholdingOptionsButtonGroup = uibuttongroup(app.UIFigure);
            app.ThresholdingOptionsButtonGroup.Title = 'Thresholding Options';
            app.NoButton = uiradiobutton(app.ThresholdingOptionsButtonGroup);
            app.NoButton.Text = 'Automatic';
            app.NoButton.Value = true;
            app.YesButton = uiradiobutton(app.ThresholdingOptionsButtonGroup);
            app.YesButton.Text = 'Manual';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CellAnalysisApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end