classdef DevApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ChannelSelectDropDown       matlab.ui.control.DropDown
        ChannelSelectDropDownLabel  matlab.ui.control.Label
        OptionsButtonGroup          matlab.ui.container.ButtonGroup
        ManualButton                matlab.ui.control.RadioButton
        AutoButton                  matlab.ui.control.RadioButton
        TextArea                    matlab.ui.control.TextArea
        TestButton                  matlab.ui.control.Button
    end

    
    methods (Access = private)
        %Feel free to modify the below functions as you find necessary


        %*****************************************************************
        %*Toolbox Interfacing Functions - for module setup
        %*****************************************************************


            %Workspace Importer
            %*****************************************************************
            %*pulls all of the metadata associated with the import of a
            %*file/batch. use '~' to exclude unneeded parameters. use
            %*bracket notation when gathering output.
            %*****************************************************************
            %Input: app
            %Output: individual parameters
            function [batch, batchNum, channelNum, dimension, fileName, fileSaveLocation, homePath, fileNum, mask, nStacks, resultsLocation, sessionNum, fileImportLocation] = WorkspaceImporter(~)
                batch = evalin('base', 'batch'); %(logical) 0 for single file, 1 for batch
                batchNum = evalin('base', 'batchNum'); %(num) subsession number (i.e sessionNum = X, batchNum = Y for session X, file/batch Y)
                channelNum = evalin('base', 'channelNum'); %(num) number of channels present in the image
                dimension = evalin('base', 'dimension'); %(string) '2D' for 2d images, 'Cryo' for cryosection images, '3D' for 3d images
                fileName = evalin('base', 'fileName'); %(string) for single files, fileName contains the file name. for batches, contains 'N/A' and fileNames must be harvested in a loop (built into the 'Toolbox Hub' function below)
                fileSaveLocation = evalin('base', 'fileSaveLocation'); %(string) file path for the 'Processed_Images' folder. this is where images used in modules should be saved.
                homePath = evalin('base', 'homePath'); %(string) file path for the subsession folder. this is what contains the 'Source_Images' and 'Processed_Images' folders in addition to results.csv and metadata.txt
                fileNum = evalin('base', 'loop'); %(num) number of files being imported (1 for single, X for batch)
                mask = evalin('base', 'mask'); %(logical) 0 for no mask, 1 for mask (positive and negative masks are overlayed in the same way)
                nStacks = evalin('base', 'nStacks'); %(num) number of z-slices (only non-one for 3d images)
                resultsLocation = evalin('base', 'resultsLocation'); %(string) file path for Results.csv
                sessionNum = evalin('base', 'sessionNum'); %(num) session number (i.e sessionNum = X, batchNum = Y for session X, file/batch Y)
                fileImportLocation = evalin('base', 'fileImportLocation'); %(string) file path for the 'Source_Images' folder. this is where freshly imported images are placed. use this as the source for pulling images
            end


            %Results Setup
            %*****************************************************************
            %*opens the results.csv file (in append) for writing results to.
            %*starts by creating a header with the process name & timestamp.
            %*MATLAB Documentation: https://www.mathworks.com/help/matlab/ref/fprintf.html
            %*****************************************************************
            %Input: app, resultsLocation(string, file path), fileEnding(string), selectedChannel(num)
            %Output: fileID handle (call to print to results)
            function results = resultsSetup(~, resultsLocation, fileEnding, selectedChannel)
                results = fopen(resultsLocation,'a');
                timestamp = strjoin(string(fix(clock)),'_');
                header = strcat(timestamp, fileEnding, '_C_', string(selectedChannel));
                fprintf(results, '------------- %s -------------\n', header);
                fprintf(results, '%s, %s, %s \n', 'fileName', 'TestAHeader', 'TestBHeader'); %modify me!
            end


            %Results Printer
            %*****************************************************************
            %*prints to the results file opened by the Results Setup
            %*function. modification is required depending on what is being
            %*printed. 
            %*MATLAB Documentation: https://www.mathworks.com/help/matlab/ref/sprintf.html
            %*                      https://www.mathworks.com/help/matlab/ref/fprintf.html
            %*****************************************************************
            %Input: app, results(handle), variables to print
            %Output: N/A, prints variables to results.csv
            function resultsPrinter(~, batch, results, fileName, TestA, TestB)
                if batch == 0
                    uiwait(msgbox(sprintf('TestA: %s\nTestB: %s', TestA, TestB))); %modify me!
                end
    
                fprintf(results, '%s, %s, %s \n', fileName, TestA, TestB); %modify me!
            end


            %Raw Data Folder Creator
            %*****************************************************************
            %*creates folder for raw data. created only when needed to avoid
            %*clutter on sessions which do not need to store raw data.
            %*potential use of raw data folder would be saving tables/graphs
            %*****************************************************************
            %Input: app, fileSaveLocation(string)
            %Output: rawData(string, file path for rawData folder)
            function rawData = rawDataCreator(~, fileSaveLocation)
                rawData = strcat(fileSaveLocation, filesep, 'Raw_Data');
                if ~exist(rawData, 'dir')
                    mkdir(rawData);
                end
            end


        %*****************************************************************
        %*Image Interfacing Functions - for image processing
        %*****************************************************************


            %Mask Importer
            %*****************************************************************
            %*imports roi from image to the roi manager for future use
            %*****************************************************************
            %Input: app, mask(logical), dimension(string) image open in ImageJ
            %Output: N/A, roi in roi manager
            function maskImporter(~, mask, dimension)
                if mask == 1
                    if strcmp(dimension, '3D') == 1
                        ij.IJ.run("To ROI Manager");
                    else
                        ij.IJ.runMacro("Roi.setPosition(1)");
                        ij.IJ.runMacro('roiManager("Add")');
                    end
                end
            end
    
    
            %Mask Applicator
            %*****************************************************************
            %*applies a mask which has been added to the roi manager. sets the
            %*unwanted regions to 'value' (typically NaN or 0). Use NaN when
            %*needing to filter values out.
            %*****************************************************************
            %Input: app, value(num), mask(logical), image open in ImageJ, roi 
            %       in roi manager
            %Output: N/A
            function maskApplicator(~, value, mask, dimension, fileSaveLocation, newFileName)  
                if mask == 1
                    if isnan(value)
                        ij.IJ.run("32-bit");
                    end

                    if strcmp(dimension, '3D') == 0
                        ij.IJ.runMacro('roiManager("Select", 0)');
                        ij.IJ.runMacro(strcat('changeValues(0,255,', num2str(value), ')'));
                        ij.IJ.runMacro('close("ROI Manager")');
                    else
                        fullName = strcat(fileSaveLocation, filesep, newFileName, ".csv");
                        ij.IJ.runMacro('roiManager("List")');
                        ij.IJ.saveAs("Results", fullName);
                        ij.IJ.runMacro(strcat("close('", newFileName, ".csv", "')")); 
                        data = table2cell(readtable(fullName));
                        delete(fullName);
                        lastIndex = str2double(string(data(end,2)));
                        for j=0:lastIndex
                            ij.IJ.runMacro(strcat('roiManager("Select", ',  string(j), ')'));
                            ij.IJ.runMacro(strcat('changeValues(0,255,', num2str(value), ')'));
                        end
                        ij.IJ.runMacro('close("ROI Manager")');
                    end
                end
            end
    
    
            %Slice Selector
            %*****************************************************************
            %*slices image into individual channels. selects the desired
            %*channel and closes the others
            %*****************************************************************
            %Input: app, channelNum(num), selectedChannel(num), fileName, image open in ImageJ
            %Output: N/A, leaves only selected channel open
            function sliceSelector(~, channelNum, selectedChannel, fileName)
                if channelNum > 1
                    ij.IJ.run("Stack Slicer", "split_channels stack_order=XYCZT");
    
                    possibleChannels = 0:1:channelNum - 1;
                    exclude = selectedChannel;
                    channels = setdiff(possibleChannels, exclude);
    
                    for i=1:(channelNum - 1)
                        channelToClose = string(channels(i));
                        ij.IJ.runMacro(strcat("close('", fileName, ' - C=', channelToClose, "')"));
                    end
    
                    ij.IJ.selectWindow(strcat(fileName, ' - C=', string(selectedChannel)));
                end
            end


            %Unit Converter
            %*****************************************************************
            %*imports an image's units and resolution
            %*****************************************************************
            %Input: app, image(string, file path to image)
            %Output: Unit(string, name of unit), Value(num, conversion
            %        factor i.e. 1 Unit = Value pixels) 
            function [Unit, Value] = unitConvert(~, image)
                imageInfo = imfinfo(image, 'tif');
                if round(imageInfo.XResolution, 2) == round(imageInfo.YResolution, 2)
                    Unit = 'micron';
                    Value = imageInfo.XResolution;
                else
                    Unit = 'pixel';
                    Value = 1;
                end
            end


        %*****************************************************************
        %*Hub Functions - combining toolbox and image interfacing functions
        %*****************************************************************


            %Binarizer
            %*****************************************************************
            %*takes an image, processes it, and produces a binarized version
            %*****************************************************************
            %Input: app, yesVal(logical, allows for user to choose between auto
            %       and manual thresholding options, collect from), 
            %       channelNum(num), selectedChannel(num), dimension(string), 
            %       fileName(string), fileImportLocation(string),
            %       fileSaveLocation(string), fileEnding(string), mask(logical)
            %Output: fullSave(string, file path to binarized image)
            function fullSave = Binarizer(app, yesVal, channelNum, selectedChannel, dimension, fileName, fileImportLocation, fileSaveLocation, fileEnding, mask)
                fileToOpen = strcat(fileImportLocation, filesep, fileName);
    
                ij.IJ.open(fileToOpen);
    
                %importing mask
                maskImporter(app, mask, dimension);

                %selecting channel
                sliceSelector(app, channelNum, selectedChannel, fileName);
    
                [~, fileNameRemoved, ~] = fileparts(fileName);
                newFileName = strcat(fileNameRemoved, fileEnding, '_C_', string(selectedChannel));
    
                fullSave = strcat(fileSaveLocation, filesep, newFileName);
    
                %setting value of areas inside of mask to 0 to avoid
                %issues with thresholding
                maskApplicator(app, 0, mask, dimension, fileSaveLocation, newFileName);

                %binarizing image
                if yesVal == 1
                    ij.IJ.run('Auto Local Threshold');
                else
                    if strcmp(dimension, '3D') == 1
                        ij.IJ.run("Auto Local Threshold", "method=Mean radius=30 parameter_1=-5 parameter_2=0 white stack");
                    else
                        ij.IJ.run("Auto Local Threshold", "method=Mean radius=30 parameter_1=-5 parameter_2=0 white");
                    end
                end
    
                %setting value of areas inside of mask to NaN
                maskApplicator(app, NaN, mask, dimension, fileSaveLocation, newFileName);

                %saving binary image and closing window
                ij.IJ.run("Select None");
                ij.IJ.saveAs('Tiff', fullSave);
                ij.IJ.runMacro('close("*")');
            end
    

            %Toolbox Hub
            %*****************************************************************
            %*sets up files for processing, calculating, and displaying results.
            %*loops for number of files in a batch folder or once for single 
            %*files. this is the heart of the toolbox's functionality.
            %*this function is a shell and must be modified when adding
            %*other functions and adding custom processes.
            %*****************************************************************
            %Input: app, batch(logical), fileImportLocation(string),
            %       fileNum(num), anything needed for calling other functions
            %Output: N/A
            function toolboxHub(~, batch, fileImportLocation, fileNum)
                if batch == 1
                    folder = dir(fileImportLocation);
                    %https://stackoverflow.com/questions/27337514/matlab-dir-without-and
                    %.DS_Store is MacOS exclusive and added by the OS to folders.
                    %it must be filtered out for proper function.
                    folder = folder(~ismember({folder.name},{'.','..','.DS_Store'}));
                else
                    %only passed if single image is imported
                    fileName = evalin('base', 'fileName');
                end
    
                for i=1:fileNum
                    if batch == 1
                        fileName = folder(i).name;
                    end
    
                    %write
                    %code
                    %here

                end
    
                %insert closing statements like closing files, etc
            end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            %*************************************************************
            %*example module using some of the above functions. takes
            %*imported image, binarizes it, saves a copy, opens that copy,
            %*and prints a version of hello world to the screen (if single
            %*file) and the results file
            %*************************************************************

            %calling workspace importer
            [batch, ~, channelNum, dimension, fileName, fileSaveLocation, ~, loop, mask, ~, resultsLocation, ~, fileImportLocation] = WorkspaceImporter(app);

            %grabbing values from gui
            optionValue = app.ManualButton.Value;
            selectedChannel = str2double(app.ChannelSelectDropDown.Value) - 1;

            %closing DevApp window
            closereq();

            %creating fileEnding that will be appended to the fileName for
            %saving. also is used in the results.csv heading
            fileEnding = '_TestEnding';

            %calling results setup
            results = resultsSetup(app, resultsLocation, fileEnding, selectedChannel);

            %toolbox hub
            if batch == 1
                folder = dir(fileImportLocation);
                %https://stackoverflow.com/questions/27337514/matlab-dir-without-and
                %.DS_Store is MacOS exclusive and added by the OS to folders.
                %it must be filtered out for proper function.
                folder = folder(~ismember({folder.name},{'.','..','.DS_Store'}));
            else
                %only passed if single image is imported
                fileName = evalin('base', 'fileName');
            end

            for i=1:loop
                if batch == 1
                    fileName = folder(i).name;
                end

                %calling binarizer
                binaryLocation = Binarizer(app, optionValue, channelNum, selectedChannel, dimension, fileName, fileImportLocation, fileSaveLocation, fileEnding, mask);

                %opeining saved binary image
                ij.IJ.open(strcat(binaryLocation, '.tif'));

                %setting what will be printed to results
                TestA = 'Hello';
                TestB = 'World';

                %calling resultsPrinter
                resultsPrinter(app, batch, results, fileName, TestA, TestB)
            end

            %closing results.csv file (done outside of loop)
            fclose(results);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TestButton = uibutton(app.UIFigure, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test!';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'This is an app that serves as a holder for modular functions for use in developing custom functions inside the toolbox. Contains an example module for visualization.'};
            app.OptionsButtonGroup = uibuttongroup(app.UIFigure);
            app.OptionsButtonGroup.Title = 'Options';
            app.AutoButton = uiradiobutton(app.OptionsButtonGroup);
            app.AutoButton.Text = 'Auto';
            app.AutoButton.Value = true;
            app.ManualButton = uiradiobutton(app.OptionsButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.ChannelSelectDropDownLabel = uilabel(app.UIFigure);
            app.ChannelSelectDropDownLabel.Text = 'Channel Select';
            app.ChannelSelectDropDown = uidropdown(app.UIFigure);
            app.ChannelSelectDropDown.Items = {'', '1', '2', '3', '4', '5', '6'};
            app.ChannelSelectDropDown.Value = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DevApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end