classdef FiberOrientationApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageQuantificationToolboxUIFigure  matlab.ui.Figure
        ExportOptionsButtonGroup        matlab.ui.container.ButtonGroup
        Image_3                         matlab.ui.control.Image
        ExportCheckBox                  matlab.ui.control.CheckBox
        Diameter                        matlab.ui.control.NumericEditField
        ApproxFiberDiameterpixelsLabel  matlab.ui.control.Label
        GraphOptionsButtonGroup         matlab.ui.container.ButtonGroup
        SaveasTIFCheckBox               matlab.ui.control.CheckBox
        Image_2                         matlab.ui.control.Image
        SaveasFIGCheckBox               matlab.ui.control.CheckBox
        DisplayCheckBox                 matlab.ui.control.CheckBox
        FiberOrientationLabel           matlab.ui.control.Label
        ChannelforAnalysisDropDown      matlab.ui.control.DropDown
        ChannelforAnalysisDropDownLabel  matlab.ui.control.Label
        OrientationJOptionsButtonGroup  matlab.ui.container.ButtonGroup
        Image                           matlab.ui.control.Image
        ManualButton                    matlab.ui.control.RadioButton
        AutomaticButton                 matlab.ui.control.RadioButton
        SubmitButton                    matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        %intakes parameters needed for accessing and processing an image
        %with OrientationJ. returns location of .txt file containing
        %OrientationJ data.
        function fullSave = Orientation(~, yesVal, channelNum, selectedChannel, dimension, fileName, fileImportLocation, rawData, fileEnding, mask, radius)
                fileToOpen = strcat(fileImportLocation, filesep, fileName);

                ij.IJ.open(fileToOpen);

                [~, fileNameRemoved, ~] = fileparts(fileName);
                newFileName = strcat(fileNameRemoved, fileEnding, '_C_', string(selectedChannel));

                fullSave = strcat(rawData, filesep, newFileName);

                %importing mask, must use Roi.setPosition for 2D + Cryo
                if mask == 1
                    if strcmp(dimension, '3D') == 1
                        ij.IJ.run("To ROI Manager");
                    else
                        ij.IJ.runMacro("Roi.setPosition(1)");
                        ij.IJ.runMacro('roiManager("Add")');
                    end
                end

                if channelNum > 1
                    ij.IJ.run("Stack Slicer", "split_channels stack_order=XYCZT");

                    %code to remove unused windows. might be redundant but
                    %could help with memory usage when windows are processed
                    possibleChannels = 0:1:channelNum - 1;
                    exclude = selectedChannel;
                    channels = setdiff(possibleChannels, exclude);
    
                    for i=1:(channelNum - 1)
                        channelToClose = string(channels(i));
                        ij.IJ.runMacro(strcat("close('", fileName, ' - C=', channelToClose, "')"));
                    end

                    ij.IJ.selectWindow(strcat(fileName, ' - C=', string(selectedChannel)));
                end

                %sets value of areas inside of mask to 0
                if mask == 1
                    if strcmp(dimension, '3D') == 0
                        ij.IJ.runMacro('roiManager("Select", 0)');
                        ij.IJ.runMacro('changeValues(0,255,0)');
                        ij.IJ.runMacro('close("ROI Manager")');
                    else
                        ij.IJ.runMacro('roiManager("List")');
                        ij.IJ.saveAs("Results", strcat(fullSave, ".csv"));
                        ij.IJ.runMacro(strcat("close('", newFileName, ".csv", "')")); 
                        data = table2cell(readtable(strcat(fullSave, ".csv")));
                        delete(strcat(fullSave, ".csv"));
                        lastIndex = str2double(string(data(end,2)));
                        for j=0:lastIndex
                            ij.IJ.runMacro(strcat('roiManager("Select", ',  string(j), ')'));
                            ij.IJ.runMacro('changeValues(0,255,0)');
                        end
                        ij.IJ.runMacro('close("ROI Manager")'); %prompt might interupt
                    end
                end
    
                if yesVal == 1
                    uiwait(msgbox('Please ensure that you select the "Show Table" option in OrientationJ. Analysis cannot be completed without table data.'));
                    ij.IJ.run("OrientationJ Vector Field");
                    uiwait(msgbox("Please press 'Ok' when the table has generated."))
                    ij.IJ.saveAs("Results", fullSave);
                    ij.IJ.runMacro('close("*")');
                    ij.IJ.runMacro(strcat('close("', newFileName, '")'));
                else
                    ij.IJ.run("OrientationJ Vector Field", strcat('"tensor=', num2str(radius), ' gradient=4 radian=on vectorgrid=10 vectorscale=80.0 vectortype=0 vectoroverlay=off vectortable=on "')); %gaussian gradient. appears to be the best solution, expecially for dense images
                    ij.IJ.saveAs("Results", fullSave);
                    ij.IJ.runMacro('close("*")');
                    ij.IJ.runMacro(strcat('close("', newFileName, '")'));
                end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            
            %import "cube" to pull data from workspace
            batch = evalin('base', 'batch');
            fileNum = evalin('base', 'fileNum');
            channelNum = evalin('base', 'channelNum');
            dimension = evalin('base', 'dimension'); 
            fileImportLocation = evalin('base', 'fileImportLocation');
            fileSaveLocation = evalin('base', 'fileSaveLocation');
            mask = evalin('base', 'mask');

            %assigning other necessary parameters
            selectedChannel = str2double(app.ChannelforAnalysisDropDown.Value) - 1;
            yesVal = app.ManualButton.Value;
            displayVal = app.DisplayCheckBox.Value;
            saveVal = app.SaveasFIGCheckBox.Value;
            saveValTIF = app.SaveasTIFCheckBox.Value;
            exportVal = app.ExportCheckBox.Value;
            fileEnding = '_FibOrient';
            diameter = app.Diameter.Value;
            radius = diameter / 2;

            %checking that inputs are valid
            if isnan(selectedChannel)
                uiwait(msgbox("Please select a valid channel for analysis.", 'Error', 'error'));
                return;
            end

            if radius <= 0
                uiwait(msgbox("Please enter a valid diameter.", 'Error', 'error'));
                return;
            end

            %close ui window
            closereq();

            %opening results.csv and printing header
            resultsLocation = evalin('base', 'resultsLocation');
            results = fopen(resultsLocation,'a'); %appending
            timestamp = strjoin(string(fix(clock)),'_');
            header = strcat(timestamp, fileEnding, '_C_', string(selectedChannel));
            fprintf(results, '-------------%s-------------\n', header);
            fprintf(results, '%s, %s, %s \n', 'fileName', 'orientationMean', 'orientationStd');

            %preping processing exclusive to single or batch images
            if batch == 1           
                folder = dir(fileImportLocation);
                %https://stackoverflow.com/questions/27337514/matlab-dir-without-and
                %.DS_Store is MacOS exclusive and added by the OS to folders.
                %it must be filtered out for proper function.
                folder = folder(~ismember({folder.name},{'.','..','.DS_Store'}));

            else
                fileName = evalin('base', 'fileName');
            end

            %create folder for raw data. created only when needed to avoid
            %clutter on sessions which do not store raw data.
            rawData = strcat(fileSaveLocation, filesep, 'Raw_Data');
            if ~exist(rawData, 'dir')
                mkdir(rawData);
            end

            %processing loop (does not loop for single files)
            for i=1:fileNum
                if batch == 1
                    fileName = folder(i).name;
                end

                fullSave = Orientation(app, yesVal, channelNum, selectedChannel, dimension, fileName, fileImportLocation, rawData, fileEnding, mask, radius);

                %importing only orientation and energy data from
                %OrientationJ table
                opts = detectImportOptions(fullSave);
                opts.SelectedVariableNames = {'Orientation', 'Energy'};
                data=readtable(fullSave, opts);
                data2=table2array(data);

                if exportVal == 1
                    [~, fileNameRemoved, ~] = fileparts(fileName);
                    assignin('base', strcat(fileNameRemoved, "_orient"), data2(:,1));
                end

                %binning energy data by orientation. done twice to
                %alliviate issues with vertical slices having incorrect
                %means & std (see if/else below)
                %binning mechanism written by Dr. Tyler Tuttle
                for j=1:2
                    if j == 1
                        Orient = data2(:,1);
                        Energy = data2(:,2);
                        edges = -90.05:.1:90.05; %creating edges of bins
                        middle = -90:.1:90; %middle of bins
                        n = zeros(1, length(edges)-1); %preallocation of n
                        binSum = zeros(1, length(edges)-2); %preallocation of binSum
                        deviation = zeros(1, length(edges)-2);  %preallocation of deviation
                    else
                        %because we are working with fibers -10 deg == 170
                        %deg. using this shift to correctly evaluate
                        %vertical fibers
                        Orient(Orient<0) = Orient(Orient<0) + 180;
                        edges = -0.05:.1:180.05; %new 0-180 bins
                        middle = 0:.1:180;
                    end
                
                    for k=1:(length(edges)-1)
                        I=Orient>edges(k) & Orient<=edges(k+1); %logical indexing roughly 2x faster than find
                        n(k)=sum(Energy(I)); %this effectively weights the orientation vectors using energy values  
                    end
                            
                    if j == 1
                        %makes -90 & 90 the same bin. this happens automatically for i == 2
                        %as a value of -90 + 180 = 90
                        n(1)=n(1)+n(end); n(end)=n(1);
                    end
                    
                    %summing bins (think array of n middle values. ie 4 
                    %values of 60 deg = 240)
                    for l=1:(length(edges)-2)
                        binSum(l) = n(l) * middle(l);
                    end
                    
                    %sum of all bin arrays
                    dataTotal = sum(binSum);

                    %total of weight
                    totalBinned = sum(n) - n(end);
                
                    orientationMean = dataTotal/totalBinned;
                
                    for m=1:(length(edges)-2)
                        deviation(m) = n(m) * ((middle(m) - orientationMean) ^2);
                    end
                     
                    orientationStd = sqrt(sum(deviation) / (totalBinned));
                
                    %saving A & B values
                    if j == 1
                        middleA = middle;
                        orientationMeanA = orientationMean;
                        orientationStdA = orientationStd;
                        nA = n;
                    else
                        middleB = middle;
                        orientationMeanB = orientationMean;
                        orientationStdB = orientationStd;
                    end
                end
                    
                    %selecting between -90-90 vs 0-180
                    if orientationStdA > orientationStdB
                        orientationMean = orientationMeanB;
                        orientationStd = orientationStdB;
                        middle = middleB;
                    else
                        orientationMean = orientationMeanA;
                        orientationStd = orientationStdA;
                        middle = middleA;
                        n = nA;
                    end
                    
                    if displayVal == 1 || saveVal == 1 || saveValTIF == 1
                        fig = figure;
                        tiledlayout(2,1)
                        
                        nexttile
                        bar(middle,n)
                        title('Fiber Orientation Histogram')
                        xlabel('Orientation (°)')
                        ylabel('Relative Weight (unitless)')
                        
                        angle = deg2rad(middle);
                        angleAcross = deg2rad(middle + 180);
                        
                        totalAngle = cat(1, angle, angleAcross);
                        totalRho = cat(1, n, n);
                        
                        nexttile
                        polarplot(totalAngle, totalRho, 'color', [0 0.4470 0.7410]);
                        title('Fiber Orientation Polar Plot')
                        rticklabels({});

                        if saveVal == 1
                            saveas(fig, strcat(fullSave, '_Graph.fig'));
                        end

                        if saveValTIF == 1
                            saveas(fig, strcat(fullSave, '_Graph.tif'));
                        end

                        if displayVal == 0
                            close(fig);
                        end
                    end

                    if batch == 0
                        uiwait(msgbox(sprintf('Orientation Mean: %f\nOrientation Standard Deviation: %f', orientationMean, orientationStd)));
                    end

                    fprintf(results, '%s, %f, %f \n', fileName, orientationMean, orientationStd);
            end

            fclose(results);
            
            %calling next element of UI
            MEMLQuantToolboxApp;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImageQuantificationToolboxUIFigure = uifigure('Visible', 'off');
            app.ImageQuantificationToolboxUIFigure.Name = 'Image Quantification Toolbox';
            app.SubmitButton = uibutton(app.ImageQuantificationToolboxUIFigure, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.OrientationJOptionsButtonGroup = uibuttongroup(app.ImageQuantificationToolboxUIFigure);
            app.OrientationJOptionsButtonGroup.Title = 'OrientationJ Options';
            app.AutomaticButton = uiradiobutton(app.OrientationJOptionsButtonGroup);
            app.AutomaticButton.Text = 'Automatic';
            app.AutomaticButton.Value = true;
            app.ManualButton = uiradiobutton(app.OrientationJOptionsButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.Image = uiimage(app.OrientationJOptionsButtonGroup);
            app.Image.Tooltip = {'Automatic - Uses the following options: tensor=radius gradient=4 radian=on vectorgrid=1 vectorscale=80.0 vectortype=0 vectoroverlay=off vectortable=on'; 'Manual - Simply opens OrientationJ Vector Field allowing the user to have free reign over all options.'};
            app.Image.ImageSource = 'image.png';
            app.ChannelforAnalysisDropDownLabel = uilabel(app.ImageQuantificationToolboxUIFigure);
            app.ChannelforAnalysisDropDownLabel.Text = 'Channel for Analysis';
            app.ChannelforAnalysisDropDown = uidropdown(app.ImageQuantificationToolboxUIFigure);
            app.ChannelforAnalysisDropDown.Items = {' ', '1', '2', '3', '4', '5', '6'};
            app.ChannelforAnalysisDropDown.Value = ' ';
            app.FiberOrientationLabel = uilabel(app.ImageQuantificationToolboxUIFigure);
            app.FiberOrientationLabel.Text = 'Fiber Orientation';
            app.GraphOptionsButtonGroup = uibuttongroup(app.ImageQuantificationToolboxUIFigure);
            app.GraphOptionsButtonGroup.Title = 'Graph Options';
            app.DisplayCheckBox = uicheckbox(app.GraphOptionsButtonGroup);
            app.DisplayCheckBox.Text = 'Display';
            app.DisplayCheckBox.Value = true;
            app.SaveasFIGCheckBox = uicheckbox(app.GraphOptionsButtonGroup);
            app.SaveasFIGCheckBox.Text = 'Save as FIG';
            app.Image_2 = uiimage(app.GraphOptionsButtonGroup);
            app.Image_2.Tooltip = {'Display - Displays a histogram and polar plot of weighted orientations.'; ''; 'Save - Saves the weighted orientation histogram and polar plot in the session storage in the selected file format.'};
            app.Image_2.ImageSource = 'image.png';
            app.SaveasTIFCheckBox = uicheckbox(app.GraphOptionsButtonGroup);
            app.SaveasTIFCheckBox.Text = 'Save as TIF';
            app.ApproxFiberDiameterpixelsLabel = uilabel(app.ImageQuantificationToolboxUIFigure);
            app.ApproxFiberDiameterpixelsLabel.Text = 'Approx. Fiber Diameter (pixels)';
            app.Diameter = uieditfield(app.ImageQuantificationToolboxUIFigure, 'numeric');
            app.ExportOptionsButtonGroup = uibuttongroup(app.ImageQuantificationToolboxUIFigure);
            app.ExportOptionsButtonGroup.Title = 'Export Options';
            app.ExportCheckBox = uicheckbox(app.ExportOptionsButtonGroup);
            app.ExportCheckBox.Text = 'Save to workspace?';
            app.ExportCheckBox.Value = true;
            app.Image_3 = uiimage(app.ExportOptionsButtonGroup);
            app.Image_3.Tooltip = {'Saves 1-D arrays of orientation values to the workspace. Arrays will be cleared after the toolbox is closed.'};
            app.Image_3.ImageSource = 'image.png';
            % Show the figure after all components are created
            app.ImageQuantificationToolboxUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FiberOrientationApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageQuantificationToolboxUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageQuantificationToolboxUIFigure)
        end
    end
end