classdef ImporterApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageQuantificationToolboxUIFigure  matlab.ui.Figure
        SessionRestoreButton           matlab.ui.control.Button
        Image_2                        matlab.ui.control.Image
        Image_5                        matlab.ui.control.Image
        ModificationsButtonGroup       matlab.ui.container.ButtonGroup
        SandboxButton                  matlab.ui.control.CheckBox
        MaskButtonNeg                  matlab.ui.control.CheckBox
        BrightNContButton              matlab.ui.control.CheckBox
        Image_4                        matlab.ui.control.Image
        CropButton                     matlab.ui.control.CheckBox
        MaskButtonPos                  matlab.ui.control.CheckBox
        RotateButton                   matlab.ui.control.CheckBox
        NumberofChannelsDropDown       matlab.ui.control.DropDown
        NumberofChannelsDropDownLabel  matlab.ui.control.Label
        FileImporterLabel              matlab.ui.control.Label
        NumberofFilesButtonGroup       matlab.ui.container.ButtonGroup
        BatchButton                    matlab.ui.control.RadioButton
        SingleButton                   matlab.ui.control.RadioButton
        ImportButton                   matlab.ui.control.Button
        MagnificationsPresentButtonGroup  matlab.ui.container.ButtonGroup
        Image_6                        matlab.ui.control.Image
        xCheckBox5sub                  matlab.ui.control.CheckBox
        xCheckBox5                     matlab.ui.control.CheckBox
        xCheckBox63                    matlab.ui.control.CheckBox
        xCheckBox25                    matlab.ui.control.CheckBox
        xCheckBox10                    matlab.ui.control.CheckBox
        ImportOptionsButtonGroup       matlab.ui.container.ButtonGroup
        Image_3                        matlab.ui.control.Image
        OptionYesButton                matlab.ui.control.RadioButton
        OptionNoButton                 matlab.ui.control.RadioButton
        DimensionButtonGroup           matlab.ui.container.ButtonGroup
        Button3d                       matlab.ui.control.RadioButton
        NonZProjectedStackButton       matlab.ui.control.RadioButton
        Button2d                       matlab.ui.control.RadioButton
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)

            %opening QuantToolboxCore.txt for use
            homeFile = fopen(strcat(matlabroot, filesep, 'QuantToolboxCore.txt'), 'r+');

            if homeFile == -1
                msgbox("Unable to find 'QuantToolboxCore.txt' in the MATLAB root folder. Please ensure the toolbox has been properly installed with 'InstallerApp.mlapp'. This requires admin privileges.");
                return
            end

            %creating new session and updating QuantToolboxCore.txt
            pathAdd = fgetl(homeFile);
            homeFolder = fgetl(homeFile);
            sessionNum = fgetl(homeFile);
            fclose(homeFile);

            if evalin('base', 'exist("sessionNum")') == 0
                %reading path to Fiji scripts & session number from .txt
                sessionNum = num2str(str2double(sessionNum) + 1);
                assignin('base', 'sessionNum', sessionNum);
                mkdir(strcat(homeFolder, filesep, 'image_storage', filesep, 'Session_', sessionNum));
                batchNum = 1;
            else
                sessionNum = evalin('base', 'sessionNum');
                batchNum = evalin('base', 'batchNum') + 1;
            end

            homeFile = fopen(strcat(matlabroot, filesep, 'QuantToolboxCore.txt'), 'r+');
            fprintf(homeFile, '%s\n', pathAdd);
            fprintf(homeFile, '%s\n', homeFolder);
            fprintf(homeFile, '%s', sessionNum);
            fclose(homeFile);

            assignin('base', 'batchNum', batchNum);

            %give user session number
            uiwait(msgbox(sprintf('Session Number: %s \nFile/Batch Number: %s', sessionNum, num2str(batchNum))));

            %assigning dimension value
            if app.Button2d.Value
                dimension = '2D';
            elseif app.NonZProjectedStackButton.Value
                dimension = 'Cryo';
            elseif app.Button3d.Value
                dimension = '3D';
            end
            
            assignin('base', 'dimension', dimension);
            assignin('base', 'sessionNum', sessionNum);

            %assigning number of channels
            channelNum = str2double(app.NumberofChannelsDropDown.Value);
            assignin('base', 'channelNum', channelNum);

            %assigning channel value
            if channelNum == 1
                channel = '1chan';
            elseif channelNum > 1
                channel = 'Multichan';
            else
                uiwait(msgbox("Please enter a number of channels.", "Error", "error"));
                return
            end

            %check for 63x series
            %this system needs to be made more robust
            if app.xCheckBox63.Value == 0
                uiwait(msgbox("The file must contain a 63x series for analysis.", "Error", "error"));
                return
            end

            %batch or single file
            batch = app.BatchButton.Value;
            assignin('base', 'batch', batch);

            %sandbox mode?
            sandbox = app.SandboxButton.Value;

            if sandbox == 1
                app.CropButton.Value = 0;
                app.RotateButton.Value = 0;
                app.BrightNContButton.Value = 0;
            end

            %crop needed?
            crop = app.CropButton.Value;

            %brightness and contrast adjustments needed?
            brightNCont = app.BrightNContButton.Value;

            %rotation needed?
            rotate = app.RotateButton.Value;

            %assigning if mask is needed
            if app.MaskButtonNeg.Value == 1 && app.MaskButtonPos.Value
                uiwait(msgbox("You can only select one mask type."));
                return
            elseif app.MaskButtonPos.Value == 1
                maskQual = 1;
            elseif app.MaskButtonNeg.Value == 1
                maskQual = -1;
            else
                maskQual = 0;
            end

            if maskQual ~= 0
                mask = 1;
            else
                mask = 0;
            end
            assignin('base', 'mask', mask);

            %position of the 63x series in Bio-Formats Importer
            %again needs to be made more robust
            position63 = num2str(app.xCheckBox5.Value + app.xCheckBox5sub.Value + app.xCheckBox10.Value + app.xCheckBox25.Value + app.xCheckBox63.Value); 
               
            %commented out for if manual options becomes functional
            %this would likely require the addition of a clone of the
            %bioformats menu to get user options.
            options = app.OptionYesButton.Value;
            
            %close ui window
            closereq();

            %combining dimension and channel into single string
            status = strcat(dimension, channel);

            %calling ImageJ through MATLAB
            addpath(pathAdd);
            ImageJ;
            ij.IJ.runMacro('close("*")');
            clc

            %preping batch or single file import
            if batch == 1
                holderDir = uigetdir;
                folder = dir(holderDir);
                %assignin('base', "folder", folder);
                
                %https://stackoverflow.com/questions/27337514/matlab-dir-without-and
                %.DS_Store is MacOS exclusive and added by the OS to folders.
                %it must be filtered out for proper function.
                folder = folder(~ismember({folder.name},{'.','..','.DS_Store'}));

                fileNum = size(folder, 1);
                sessionType = '_Batch';
                fileName = 'N/A';
            else
                fileNum = 1;
                sessionType = '_File';
                originalDir = string(ij.IJ.getFilePath(''));
                [~,originalName,~] = fileparts(originalDir);
                fileName = strcat(originalName, '.tif');
            end

            assignin('base', 'fileName', fileName);

            homePath = strcat(homeFolder, filesep, 'image_storage', filesep, 'Session_', sessionNum, filesep, num2str(batchNum), sessionType);
            assignin('base', 'homePath', homePath);
            fileImportLocation = strcat(homePath, filesep, 'Source_Images');
            mkdir(fileImportLocation);
            fileSaveLocation = strcat(homePath, filesep, 'Processed_Images');
            mkdir(fileSaveLocation);
            resultsLocation = strcat(homePath, filesep, '!S', sessionNum, '_', num2str(batchNum), '_Results.csv');
            assignin('base', 'resultsLocation', resultsLocation);

            settingsLog = fopen(strcat(homePath, filesep, 'metadata.txt'), 'w+');
            resultsHandle = fopen(resultsLocation,'w+');
            fclose(resultsHandle);

            assignin('base', 'fileSaveLocation', fileSaveLocation);
            assignin('base', 'fileImportLocation', fileImportLocation);
            assignin('base', 'fileNum', fileNum);
            
            %importing loop (only completes one iteration for single files)
            for i=1:fileNum 
                if batch == 1
                    originalDir = strcat(folder(i).folder, filesep, folder(i).name);
                    originalName = folder(i).name;
                end

                fullSave = strcat(fileImportLocation, filesep, originalName);

                switch status
                    case {'2D1chan', 'Cryo1chan', '3D1chan'}

                        ij.IJ.run("Bio-Formats Importer", strcat("open=",'[', originalDir, '] ', "autoscale color_mode=Colorized rois_import=[ROI manager] view=Hyperstack stack_order=XYCZT"));                 
                    
                    case {'2DMultichan', 'CryoMultichan', '3DMultichan'}             
                        if options == 1
                            if i==1
                                ij.IJ.run("Bio-Formats Importer");
                            end
                        else
                            if str2double(position63) == 1
                                ij.IJ.run("Bio-Formats Importer", strcat("open=",'[', originalDir, '] ', "autoscale color_mode=Colorized rois_import=[ROI manager] view=Hyperstack stack_order=XYCZT"));    
                            else
                                ij.IJ.run("Bio-Formats Importer", strcat("open=",'[', originalDir, '] ', "autoscale color_mode=Colorized rois_import=[ROI manager] view=Hyperstack stack_order=XYCZT series_", position63));
                            end
                        end
                end

                %projecting cryosectioned images to 2d
                if strcmp('Cryo', dimension)
                    ij.IJ.run("Z Project...", "Max Intensity");
                end

                %fetching number of stacks for 3d images
                if strcmp('3D', dimension) == 1
                    %odd issue. periodically ImageJ will not pull up a
                    %log window or will sometimes refuse to save the log
                    %window. current solution is to restart MATLAB. 
                    ij.IJ.runMacro('nSlices');
                    pause(.5); %attempting to alleviate saving issue
                    ij.IJ.saveAs("Text", strcat(fullSave, ".csv"));
                    ij.IJ.runMacro('close("Log")');
                    data = table2array(readtable(strcat(fullSave, ".csv")));
                    delete(strcat(fullSave, ".csv"));
                    nStacks = data(1) / channelNum;
                else
                    nStacks = 1;
                end

                %cropping image
                if crop == 1
                    ij.IJ.runMacro('setTool("rectangle")');
                    uiwait(msgbox("Please select a rectangular region that you would like to crop. Please press 'OK' on this message box when you are done."));
                    ij.IJ.run('Crop');
                    if strcmp(dimension, '3D') == 1
                        %calls ui element to crop z-stack. creates
                        %invisable figure to prevent further execution
                        %while window is open
                        ZStackCropMenu;
                        evalin('base', "holderFig = figure('visible', 'off');");

                        while evalin('base', 'isvalid(holderFig);') == 1 
                            pause(0.1);
                        end

                        if evalin('base', 'zCrop') == 1
                            zBounds = inputdlg({'Enter z-stack lower bound:', 'Enter z-stack upper bound:'});
                            zLowerBound = str2double(zBounds(1));
                            zUpperBound = str2double(zBounds(2));

                            %creating a cropped stackNum
                            nStacks = zUpperBound - zLowerBound + 1;
    
                            %current solution for z-stack crop. cycles through
                            %a z-stack and individually removes slices below and
                            %above a user set range. currently cannot use
                            %ImageJ 3D crop as it merges all channels into one
                            %z-stack. 
                            %current issues: slow, visually unappealing
                            for a=1:zLowerBound - 1
                                ij.IJ.runMacro(strcat('Stack.setSlice(', string(1),')'));
                                ij.IJ.run("Delete Slice", "delete=slice");
                            end
    
                            for b=zUpperBound:nStacks - 1
                                ij.IJ.runMacro(strcat('Stack.setSlice(', string(zUpperBound),')'));
                                ij.IJ.run("Delete Slice", "delete=slice");
                            end

                            evalin('base', 'clear zCrop');
                        end
                    end
                end

                assignin('base', 'nStacks', nStacks);

                %calling ImageJ B&C menu for use.
                if brightNCont == 1
                    ij.IJ.run("Brightness/Contrast...");
                    uiwait(msgbox("Make any necessary adjustments to the image brightness and contrast. Please apply these changes through the ImageJ menu. Press 'OK' on this message box when you are done."));
                    ij.IJ.runMacro('close("B&C")');
                end

                %rotating image by measuring the angle of lines drawn by
                %the user
                if rotate == 1
                    ij.IJ.runMacro('setTool("line")');
                    ij.IJ.run('ROI Manager...');
                    uiwait(msgbox(strcat("Please draw one or multiple lines which you would like to serve as the new 0", char(0176), " orientation. Please note that the direction in which they are drawn determines their angle (think of the starting point of the line as the center of a unit circle). Please add them to the ROI Manager and press 'OK' on this message box when you are done.")));
                    ij.IJ.runMacro('roiManager("Deselect")');
                    ij.IJ.runMacro('roiManager("Measure")');
                    ij.IJ.runMacro('close("ROI Manager")');
                    ij.IJ.saveAs("Results", strcat(fullSave, ".csv"));
                    ij.IJ.runMacro("close('Results')"); 
                    data = table2array(readtable(strcat(fullSave, ".csv")));
                    delete(strcat(fullSave, ".csv"));
                    angle = mean(data(:,6));

                    %consider adding message about how the line is a vector
                    
                    ij.IJ.run("Rotate...", strcat("angle=", string(angle), " grid=1 interpolation=Bilinear stack enlarge"));
                end

                if sandbox == 1
                    uiwait(msgbox("Please press 'OK' on this window after you have finished adjusting the image."));

                    if mask == 0
                        maskConfirm = questdlg('Would you like to add a mask?', 'Mask Confirmation', 'Positive Mask', 'Negative Mask', 'No', 'No');
                        switch maskConfirm
                            case 'Positive Mask'
                                mask = 1;
                                maskQual = 1;
                                assignin('base', 'mask', mask);
                            case 'Negative Mask'
                                mask = 1;
                                maskQual = -1;
                                assignin('base', 'mask', mask);
                        end
                    end
                end

                %creating masks for image
                if mask == 1
                    ij.IJ.runMacro('setTool("polygon")');
                    ij.IJ.run("ROI Manager...");

                    if strcmp(dimension, '3D') == 0
                        if maskQual == 1
                            uiwait(msgbox("Please select the regions you would like to include in analysis (must be a closed shape) and add them to the ROI Manager (tip: press 't' on your keyoard or right click on selections and click 'Add to ROI Manager'). Press 'OK' on this message box when you have finalized your selections."));
                        elseif maskQual == -1
                            uiwait(msgbox("Please select the regions you would like to exclude from analysis (must be a closed shape) and add them to the ROI Manager (tip: press 't' on your keyoard or right click on selections and click 'Add to ROI Manager'). Press 'OK' on this message box when you have finalized your selections."));
                        end

                        ij.IJ.runMacro('roiManager("deselect")');
                        ij.IJ.runMacro('roiManager("combine")');
                        if maskQual == 1
                            ij.IJ.run("Make Inverse");
                        end
                    else

                        ij.IJ.runMacro('roiManager("Associate", "true")'); %associates "show all" roi with their respective z-stack position
                        ij.IJ.runMacro('roiManager("Show All")');

                        for j=1:nStacks
                            ij.IJ.runMacro(strcat('Stack.setSlice(', string(j),')'));
                            if maskQual == 1
                                uiwait(msgbox("Please select the regions you would like to include in analysis (this current z-slice only, must be a closed shape) and add them to the ROI Manager (tip: press 't' on your keyoard or right click on selections and click 'Add to ROI Manager'). Press 'OK' on this message box when you have finalized your selections."));
                            elseif maskQual == -1
                                uiwait(msgbox("Please select the regions you would like to exclude from analysis (this current z-slice only, must be a closed shape) and add them to the ROI Manager (tip: press 't' on your keyoard or right click on selections and click 'Add to ROI Manager'). Press 'OK' on this message box when you have finalized your selections."));
                            end
                            
                            ij.IJ.runMacro('roiManager("deselect")');
                            ij.IJ.runMacro('roiManager("combine")');

                            ij.IJ.runMacro('roiManager("size")'); %consider using another command to get number of roi from roiManager due to saving issues with Log windows
                            pause(.5); %attempting to alleviate saving issue
                            ij.IJ.saveAs("Text", strcat(fullSave, ".csv"));
                            ij.IJ.runMacro('close("Log")');
                            data = table2array(readtable(strcat(fullSave, ".csv")));
                            delete(strcat(fullSave, ".csv"));
                            roiOnStack = data(1);

                            if maskQual == 1
                                if roiOnStack == 0
                                    ij.IJ.run("Select All");
                                else
                                    ij.IJ.run("Make Inverse");
                                end
                            end

                            ij.IJ.runMacro('roiManager("Add")');

                            %selecting non-combined roi for deletion
                            if roiOnStack > 0
                                indexArray = zeros(1, roiOnStack); %preallocation of array
                                for l=0:roiOnStack - 1
                                    indexArray(l+1) = l;
                                end

                                indexString = regexprep(num2str(indexArray), '\s+', ','); %converting from array to comma deliminated string
                                if roiOnStack == 1
                                    ij.IJ.runMacro(strcat('roiManager("Select",', indexString, ')'));
                                else
                                    ij.IJ.runMacro(strcat('roiManager("Select", newArray(', indexString, '))'));
                                end
                                ij.IJ.runMacro('roiManager("Delete")');
                                clear indexArray
                            end
                            
                            if maskQual == 1 || roiOnStack > 0
                                ij.IJ.runMacro('roiManager("Select", 0)');
                                ij.IJ.runMacro(strcat('Roi.setPosition(1,', string(j),',1)')); %this might need modifications for time based images
                                ij.IJ.run("From ROI Manager");
                                ij.IJ.runMacro('roiManager("Deselect")');
                                ij.IJ.runMacro('roiManager("Delete")');
                            end
                        end
                    end
                end

                %future use of binarization requires the image to be in an
                %8-bit format. this is in case non 8-bit images are input
                %and cause issues.
                ij.IJ.run("8-bit");

                ij.IJ.saveAs('Tiff', fullSave);
                ij.IJ.runMacro('close("*")');

                if mask == 1
                    ij.IJ.runMacro('close("ROI Manager")');
                end
            end

            %printing settings to metadata.txt
            fprintf(settingsLog, '%d\n', batch);
            fprintf(settingsLog, '%d\n', batchNum);
            fprintf(settingsLog, '%d\n', channelNum);
            fprintf(settingsLog, '%s\n', dimension);
            fprintf(settingsLog, '%s\n', fileName);
            fprintf(settingsLog, '%s\n', fileSaveLocation);
            fprintf(settingsLog, '%s\n', homePath);
            fprintf(settingsLog, '%d\n', fileNum);
            fprintf(settingsLog, '%d\n', mask);
            fprintf(settingsLog, '%d\n', nStacks);
            fprintf(settingsLog, '%s\n', resultsLocation);
            fprintf(settingsLog, '%s\n', sessionNum);
            fprintf(settingsLog, '%s', fileImportLocation);
            fclose(settingsLog);

            %calling next element of UI
            MEMLQuantToolboxApp;

            %-----------------Code Scraps-----------------%

            %alternative z-stack crop method. imports image as
            %a matrix which can be manipulated. currently
            %nonfunctional.
            %current issues: creates distorted images which do
            %not resemble the original. seems to be an issue
            %with either IJM.getDatasetAs() or imwrite.
            %possible benefits: maybe faster, no GUI
            %{
            evalin('base', "IJM.getDatasetAs('imageData');");
            dataSize = evalin('base', 'size(imageData);');
            stackNum = dataSize(4);

            evalin('base', strcat('imageData = imageData(:,:,:,', string(zLowerBound), ':', string(zUpperBound), ');'));

            for k=1:(zUpperBound - zLowerBound + 1)
                evalin('base', strcat('imwrite(', 'imageData(:,:,:,', string(k), '),', fullSave, '.tif , "WriteMode", "append");'));
            end
            %}
           
        end

        % Button pushed function: SessionRestoreButton
        function SessionRestoreButtonPushed(app, event)
            clear, clc
            closereq;

            uiwait(msgbox("Please navigate to and add the subsession you would like to restore."))
            
            %opening QuantToolboxCore.txt for use
            homeFile = fopen(strcat(matlabroot, filesep, 'QuantToolboxCore.txt'), 'r+');

            %reading path to Fiji scripts & session number from .txt
            pathAdd = fgetl(homeFile);
            homeFolder = fgetl(homeFile);
            fclose(homeFile);

            prevBatchLocation = uigetdir(homeFolder);
            prevBatchMetadata = fopen(strcat(prevBatchLocation, filesep, 'metadata.txt'), 'r+');

            %pulling metadata from previous session
            %could be made more efficient by only saving homePath and
            %deriving others (only saves a few bits of space)
            batch = fgetl(prevBatchMetadata);
            batchNum = fgetl(prevBatchMetadata);
            channelNum = fgetl(prevBatchMetadata);
            dimension = fgetl(prevBatchMetadata);
            fileName = fgetl(prevBatchMetadata);
            fileSaveLocation = fgetl(prevBatchMetadata);
            homePath = fgetl(prevBatchMetadata);
            fileNum = fgetl(prevBatchMetadata);
            mask = fgetl(prevBatchMetadata);
            nStacks = fgetl(prevBatchMetadata);
            resultsLocation = fgetl(prevBatchMetadata);
            sessionNum = fgetl(prevBatchMetadata);
            fileImportLocation = fgetl(prevBatchMetadata);
            fclose(prevBatchMetadata);

            %assigning metadata for use
            assignin('base', 'batch', str2double(batch));
            assignin('base', 'batchNum', str2double(batchNum));
            assignin('base', 'channelNum', str2double(channelNum));
            assignin('base', 'dimension', dimension);
            assignin('base', 'fileName', fileName);
            assignin('base', 'fileSaveLocation', fileSaveLocation);
            assignin('base', 'homePath', homePath);
            assignin('base', 'fileNum', str2double(fileNum));
            assignin('base', 'mask', str2double(mask));
            assignin('base', 'nStacks', str2double(nStacks));
            assignin('base', 'resultsLocation', resultsLocation);
            assignin('base', 'sessionNum', sessionNum);
            assignin('base', 'fileImportLocation', fileImportLocation);

            %calling ImageJ through MATLAB
            addpath(pathAdd);
            ImageJ;
            ij.IJ.runMacro('close("*")');
            clc

            %calling next element of UI
            MEMLQuantToolboxApp;
        end

        % Callback function
        function TestButtonPushed(app, event)
            evalin('base', 'exist("sessionNum")')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImageQuantificationToolboxUIFigure = uifigure('Visible', 'off');
            app.ImageQuantificationToolboxUIFigure.Name = 'Image Quantification Toolbox';
            app.DimensionButtonGroup = uibuttongroup(app.ImageQuantificationToolboxUIFigure);
            app.DimensionButtonGroup.Title = 'Dimension';
            app.Button2d = uiradiobutton(app.DimensionButtonGroup);
            app.Button2d.Text = '2D';
            app.Button2d.Value = true;
            app.NonZProjectedStackButton = uiradiobutton(app.DimensionButtonGroup);
            app.NonZProjectedStackButton.Text = 'Non Z-Projected Stack';
            app.Button3d = uiradiobutton(app.DimensionButtonGroup);
            app.Button3d.Text = '3D';
            app.ImportOptionsButtonGroup = uibuttongroup(app.ImageQuantificationToolboxUIFigure);
            app.ImportOptionsButtonGroup.Title = 'Import Options';
            app.OptionNoButton = uiradiobutton(app.ImportOptionsButtonGroup);
            app.OptionNoButton.Text = 'Automatic';
            app.OptionNoButton.Value = true;
            app.OptionYesButton = uiradiobutton(app.ImportOptionsButtonGroup);
            app.OptionYesButton.Text = 'Manual';
            app.Image_3 = uiimage(app.ImportOptionsButtonGroup);
            app.Image_3.Tooltip = {'Automatic - Uses the information from "Magnifications Present" to select the 63x channel and import it.'; 'Manual - Opens the Bio-Formats Importer and allows the user to control all import settings. Please ensure you are only giving the toolbox a single image to work with. This feature has not been extensively tested and may cause problems, particularly with batches.'};
            app.Image_3.ImageSource = 'image.png';
            app.MagnificationsPresentButtonGroup = uibuttongroup(app.ImageQuantificationToolboxUIFigure);
            app.MagnificationsPresentButtonGroup.Title = 'Magnifications Present';
            app.xCheckBox10 = uicheckbox(app.MagnificationsPresentButtonGroup);
            app.xCheckBox10.Text = '10x';
            app.xCheckBox25 = uicheckbox(app.MagnificationsPresentButtonGroup);
            app.xCheckBox25.Text = '25x';
            app.xCheckBox63 = uicheckbox(app.MagnificationsPresentButtonGroup);
            app.xCheckBox63.Text = '63x';
            app.xCheckBox63.Value = true;
            app.xCheckBox5 = uicheckbox(app.MagnificationsPresentButtonGroup);
            app.xCheckBox5.Text = '5x';
            app.xCheckBox5sub = uicheckbox(app.MagnificationsPresentButtonGroup);
            app.xCheckBox5sub.Text = '5x (Sub)';
            app.Image_6 = uiimage(app.MagnificationsPresentButtonGroup);
            app.Image_6.Tooltip = {'Different magnifications are simply for separating files which contain multiple magnifications. The actual magnification value is irrelivent to any caluclations (that information is read from the file if necessary).'; ''; 'Select 63x only if a single magnification is present (regardless of what the actual magnification is).'};
            app.Image_6.ImageSource = 'image.png';
            app.ImportButton = uibutton(app.ImageQuantificationToolboxUIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.NumberofFilesButtonGroup = uibuttongroup(app.ImageQuantificationToolboxUIFigure);
            app.NumberofFilesButtonGroup.Tooltip = {''};
            app.NumberofFilesButtonGroup.Title = 'Number of Files';
            app.SingleButton = uiradiobutton(app.NumberofFilesButtonGroup);
            app.SingleButton.Text = 'Single';
            app.SingleButton.Value = true;
            app.BatchButton = uiradiobutton(app.NumberofFilesButtonGroup);
            app.BatchButton.Text = 'Batch';
            app.FileImporterLabel = uilabel(app.ImageQuantificationToolboxUIFigure);
            app.FileImporterLabel.Text = 'File Importer   .';
            app.NumberofChannelsDropDownLabel = uilabel(app.ImageQuantificationToolboxUIFigure);
            app.NumberofChannelsDropDownLabel.Text = 'Number of Channels';
            app.NumberofChannelsDropDown = uidropdown(app.ImageQuantificationToolboxUIFigure);
            app.NumberofChannelsDropDown.Items = {'', '1', '2', '3', '4', '5', '6'};
            app.NumberofChannelsDropDown.Value = '';
            app.ModificationsButtonGroup = uibuttongroup(app.ImageQuantificationToolboxUIFigure);
            app.ModificationsButtonGroup.Title = 'Modifications';
            app.RotateButton = uicheckbox(app.ModificationsButtonGroup);
            app.RotateButton.Text = 'Rotate';
            app.MaskButtonPos = uicheckbox(app.ModificationsButtonGroup);
            app.MaskButtonPos.Text = 'Mask (+)';
            app.CropButton = uicheckbox(app.ModificationsButtonGroup);
            app.CropButton.Text = 'Crop';
            app.Image_4 = uiimage(app.ModificationsButtonGroup);
            app.Image_4.Tooltip = {'Crop - Allows the user to create a rectangular area which the image will be cropped to. If the image is 3D the user will also be prompted if they want to crop the z-stack.'; 'Rotate - Prompts the user to create a line which will be defined as the new 0° orientation. Rotates image to this new orientation. Creates black space around the image to avoid cropping corners.'; 'B&C - Opens the ImageJ brightness and contrast menu for user adjustments.'; 'Mask(+) - Allows the user to create regions of interest which will be included in calculations and view (pixels outside of region of interest are set to 0 or NaN).'; 'Mask(-) - Allows the user to create regions of interest which will be excluded from calculations and view (pixels set to 0 or NaN).'; 'Sandbox - Opens the imported image in ImageJ for the user to make any changes they want. Overrides all options except mask(+/-).'};
            app.Image_4.ImageSource = 'image.png';
            app.BrightNContButton = uicheckbox(app.ModificationsButtonGroup);
            app.BrightNContButton.Text = 'B&C';
            app.MaskButtonNeg = uicheckbox(app.ModificationsButtonGroup);
            app.MaskButtonNeg.Text = 'Mask (-)';
            app.SandboxButton = uicheckbox(app.ModificationsButtonGroup);
            app.SandboxButton.Text = 'Sandbox';
            app.Image_5 = uiimage(app.ImageQuantificationToolboxUIFigure);
            app.Image_5.Tooltip = {'This is the image importer for the toolbox. This toolbox can theoretically import and use any Bio-Formats compatable image. Please note that the importer requires that files only contain different magnifications of a single image (e.g. no multi-image .tif files). '};
            app.Image_5.ImageSource = 'image.png';
            app.Image_2 = uiimage(app.ImageQuantificationToolboxUIFigure);
            app.Image_2.Tooltip = {'Single - One image. Gives the user data from each function in the form of a message box.'; 'Batch - A folder filled with single images. Images must be uniform in magnifications and channels present. '};
            app.Image_2.ImageSource = 'image.png';
            app.SessionRestoreButton = uibutton(app.ImageQuantificationToolboxUIFigure, 'push');
            app.SessionRestoreButton.ButtonPushedFcn = createCallbackFcn(app, @SessionRestoreButtonPushed, true);
            app.SessionRestoreButton.Text = 'Session Restore';
            % Show the figure after all components are created
            app.ImageQuantificationToolboxUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImporterApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageQuantificationToolboxUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageQuantificationToolboxUIFigure)
        end
    end
end