classdef InstallerApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageQuantificationToolboxUIFigure  matlab.ui.Figure
        ToolboxInstallerLabel       matlab.ui.control.Label
        InstallLocationButtonGroup  matlab.ui.container.ButtonGroup
        CustomButton                matlab.ui.control.RadioButton
        MATLABSubdirectoryButton    matlab.ui.control.RadioButton
        InstallButton               matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InstallButton
        function InstallButtonPushed(app, event)
            %default or custom install
            installLoc = app.MATLABSubdirectoryButton.Value;
            
            closereq();

            %creating homeFolder and toolbox core
            if installLoc == 1
                homeFolder = matlabroot;
            else
                uiwait(msgbox("Please select a folder to house the toolbox."));
                homeFolder = uigetdir;
            end

            status = mkdir(strcat(homeFolder, filesep, 'image_storage'));

            if status == 0
                uiwait(msgbox("Unable to create the home folder, please ensure you have admin privileges.", 'Error', 'error'));
                return
            end

            txtFilePath = strcat(matlabroot, filesep, 'QuantToolboxCore.txt');

            file = fopen(txtFilePath, 'w'); %creates settings holder .txt file w/ write access

            if file == -1
                uiwait(msgbox("Please ensure your MATLAB folder is not read only.", 'Error', 'error'));
                return
            end

            if ispc == 1
                uiwait(msgbox("Please navigate to the Fiji.app folder you would like to use in this toolbox."));
                scriptsLocation = strcat(uigetdir, filesep, 'scripts');
            elseif ismac == 1
                uiwait(msgbox("Please navigate to the folder containing the version of the Fiji app you would like to use in this toolbox (this will most likely be the 'Applications' folder)."));
                scriptsLocation = strcat(uigetdir, filesep, 'Fiji.app', filesep, 'scripts');
            else
                uiwait(msgbox("Your operating system is not currently supported.", 'Error', 'error'));
                return
            end

            fprintf(file, '%s', scriptsLocation);
            fprintf(file, '\n%s', homeFolder);
            fprintf(file, '\n0');

            uiwait(msgbox("The MATLAB app component of the toolbox has been installed!"));

            pluginCheck = questdlg('Have you added ImageJ-MATLAB to your ImageJ plugin update list?', 'ImageJ-MATLAB Confirmation', 'Yes', 'No', 'No');

            if strcmp(pluginCheck, 'No') == 1
                uiwait(msgbox("The ImageJ-MATLAB plugin is required for the function of this toolbox. An installation resource will be opened in your default web browser when you click 'OK'."));
                web('https://imagej.net/scripting/matlab#prerequisites')
            else
                uiwait(msgbox("Launch ImporterApp.mlapp to begin use!"));
            end

            clear, clc

            %include turning on matlab-imagej updates
            %future matlab toolbox installation will be necessary, for
            %example computer vision, simulink, etc
            %https://www.mathworks.com/help/install/ug/install-noninteractively-silent-installation.html
            %https://www.mathworks.com/help/install/ug/install-using-a-file-installation-key.html
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImageQuantificationToolboxUIFigure = uifigure('Visible', 'off');
            app.ImageQuantificationToolboxUIFigure.Name = 'Image Quantification Toolbox';
            app.InstallButton = uibutton(app.ImageQuantificationToolboxUIFigure, 'push');
            app.InstallButton.ButtonPushedFcn = createCallbackFcn(app, @InstallButtonPushed, true);
            app.InstallButton.Text = 'Install';
            app.InstallLocationButtonGroup = uibuttongroup(app.ImageQuantificationToolboxUIFigure);
            app.InstallLocationButtonGroup.Title = 'Install Location';
            app.MATLABSubdirectoryButton = uiradiobutton(app.InstallLocationButtonGroup);
            app.MATLABSubdirectoryButton.Text = 'MATLAB Subdirectory';
            app.MATLABSubdirectoryButton.Value = true;
            app.CustomButton = uiradiobutton(app.InstallLocationButtonGroup);
            app.CustomButton.Text = 'Custom';
            app.ToolboxInstallerLabel = uilabel(app.ImageQuantificationToolboxUIFigure);
            app.ToolboxInstallerLabel.Text = 'Toolbox Installer';
            % Show the figure after all components are created
            app.ImageQuantificationToolboxUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InstallerApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageQuantificationToolboxUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageQuantificationToolboxUIFigure)
        end
    end
end