classdef MEMLQuantToolboxApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageQuantificationToolboxUIFigure  matlab.ui.Figure
        Release10Label                  matlab.ui.control.Label
        OpenSessionStorageButton        matlab.ui.control.Button
        MouseoveranybuttonforinfoLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        DViewerButton                   matlab.ui.control.Button
        NewFileBatchButton              matlab.ui.control.Button
        FiberOrientationButton          matlab.ui.control.Button
        HomeLabel                       matlab.ui.control.Label
        EndSessionButton                matlab.ui.control.Button
        SupportButton                   matlab.ui.control.Button
        CellAnalysisButton              matlab.ui.control.Button
        VolumeFractionButton            matlab.ui.control.Button
        ComponentFractionButton         matlab.ui.control.Button
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ComponentFractionButton
        function ComponentFractionButtonPushed(app, event)
            if evalin('base', 'channelNum') <2
                msgbox('Component Fraction requires multiple channels.');
                return
            end
            CompFracApp;
            closereq();
        end

        % Button pushed function: VolumeFractionButton
        function VolumeFractionButtonPushed(app, event)
            VolFracApp;
            closereq();
        end

        % Button pushed function: CellAnalysisButton
        function CellAnalysisButtonPushed(app, event)
            if strcmp(evalin('base', 'dimension'), '3D') == 1
                msgbox('Cell Analysis does not support 3D images.');
                return
            end
            CellAnalysisApp;
            closereq();
        end

        % Button pushed function: SupportButton
        function SupportButtonPushed(app, event)
            msgbox("Please contact Daniel.Deuel@colorado.edu if you have any issues or suggestions.");
        end

        % Button pushed function: EndSessionButton
        function EndSessionButtonPushed(app, event)
            closereq();
            fclose('all'); 
            ij.IJ.run("Quit");
            evalin('base', 'clear');
            clc
        end

        % Button pushed function: FiberOrientationButton
        function FiberOrientationButtonPushed(app, event)
            FiberOrientationApp;
            closereq();
        end

        % Button pushed function: NewFileBatchButton
        function NewFileBatchButtonPushed(app, event)
            ImporterApp;
            closereq();
        end

        % Callback function
        function FiberDiameterButtonPushed(app, event)
            FiberDiameterApp;
            closereq();
        end

        % Button pushed function: OpenSessionStorageButton
        function OpenSessionStorageButtonPushed(app, event)
            if usejava('awt')
                desktop = java.awt.Desktop.getDesktop();
                File = java.io.File(evalin('base', 'homePath'));
                desktop.open(File);
            else
                msgbox('Java AWT functionality not avalible.');
            end
        end

        % Button pushed function: DViewerButton
        function DViewerButtonPushed(app, event)
            if strcmp(evalin('base', 'dimension'), '3D') == 0
                msgbox('3D images only.', 'Error', 'error');
                return
            end
            ViewerApp;
            closereq();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImageQuantificationToolboxUIFigure = uifigure('Visible', 'off');
            app.ImageQuantificationToolboxUIFigure.Name = 'Image Quantification Toolbox';
            app.ComponentFractionButton = uibutton(app.ImageQuantificationToolboxUIFigure, 'push');
            app.ComponentFractionButton.ButtonPushedFcn = createCallbackFcn(app, @ComponentFractionButtonPushed, true);
            app.ComponentFractionButton.Tooltip = {'2D & Cryosection - Computes the volume fraction for 2 channels and divides the first channel by the second giving a ratio.'; ''; '3D - Same as 2D & cryosection but iterated through the entire z-stack.'; ''; 'Note: Does not support single channel images.'};
            app.ComponentFractionButton.Text = 'Component Fraction';
            app.VolumeFractionButton = uibutton(app.ImageQuantificationToolboxUIFigure, 'push');
            app.VolumeFractionButton.ButtonPushedFcn = createCallbackFcn(app, @VolumeFractionButtonPushed, true);
            app.VolumeFractionButton.Tooltip = {'2D & Cryosection - Binarizes the imported image and divides the number of illuminated pixes by the total area. '; ''; '3D - Same as 2D & cryosection but iterates through the entire z-stack.'};
            app.VolumeFractionButton.Text = 'Volume Fraction';
            app.CellAnalysisButton = uibutton(app.ImageQuantificationToolboxUIFigure, 'push');
            app.CellAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @CellAnalysisButtonPushed, true);
            app.CellAnalysisButton.Tooltip = {'2D & Cryosection - Binarizes the imported image and uses MATLAB blob analysis to group pixels and give statistics on said groups.'; ''; '3D - Not supported.'};
            app.CellAnalysisButton.Text = 'Cell Analysis';
            app.SupportButton = uibutton(app.ImageQuantificationToolboxUIFigure, 'push');
            app.SupportButton.ButtonPushedFcn = createCallbackFcn(app, @SupportButtonPushed, true);
            app.SupportButton.Tooltip = {'FAQ page & developer contact information. WIP!'};
            app.SupportButton.Text = 'Support';
            app.EndSessionButton = uibutton(app.ImageQuantificationToolboxUIFigure, 'push');
            app.EndSessionButton.ButtonPushedFcn = createCallbackFcn(app, @EndSessionButtonPushed, true);
            app.EndSessionButton.Tooltip = {'Closes ImageJ and the toolbox.'};
            app.EndSessionButton.Text = 'End Session';
            app.HomeLabel = uilabel(app.ImageQuantificationToolboxUIFigure);
            app.HomeLabel.Text = 'Home';
            app.FiberOrientationButton = uibutton(app.ImageQuantificationToolboxUIFigure, 'push');
            app.FiberOrientationButton.ButtonPushedFcn = createCallbackFcn(app, @FiberOrientationButtonPushed, true);
            app.FiberOrientationButton.Tooltip = {'2D & Cryosection - Uses OrientationJ to create a table of pixel orientations and energy values. These values are then used to calculate a orientation mean and standard deviation.'; ''; '3D - Same as 2D & cryosection but iterated through the entire z-stack. Can only be used on samples where the fibers are oriented to the x and y axes. Not compatable with fibers traveling through multiple z slices. '};
            app.FiberOrientationButton.Text = 'Fiber Orientation';
            app.NewFileBatchButton = uibutton(app.ImageQuantificationToolboxUIFigure, 'push');
            app.NewFileBatchButton.ButtonPushedFcn = createCallbackFcn(app, @NewFileBatchButtonPushed, true);
            app.NewFileBatchButton.Tooltip = {'Allows for a different file/batch to be imported using the toolbox file importer.'};
            app.NewFileBatchButton.Text = 'New File/Batch';
            app.DViewerButton = uibutton(app.ImageQuantificationToolboxUIFigure, 'push');
            app.DViewerButton.ButtonPushedFcn = createCallbackFcn(app, @DViewerButtonPushed, true);
            app.DViewerButton.Tooltip = {'2D & Cryosection - Not supported.'; ''; '3D - Opens one channel or the entire imported image into either the ImageJ 3D Viewer or ClearVolume.'};
            app.DViewerButton.Text = '3D Viewer';
            app.Image = uiimage(app.ImageQuantificationToolboxUIFigure);
            app.Image.Tooltip = {''};
            app.Image.ImageSource = 'image.png';
            app.MouseoveranybuttonforinfoLabel = uilabel(app.ImageQuantificationToolboxUIFigure);
            app.MouseoveranybuttonforinfoLabel.Text = 'Mouse over any button for info';
            app.OpenSessionStorageButton = uibutton(app.ImageQuantificationToolboxUIFigure, 'push');
            app.OpenSessionStorageButton.ButtonPushedFcn = createCallbackFcn(app, @OpenSessionStorageButtonPushed, true);
            app.OpenSessionStorageButton.Tooltip = {'Opens the folder containing this file/batch in the OS''s file explorer.'};
            app.OpenSessionStorageButton.Text = 'Open Session Storage';
            app.Release10Label = uilabel(app.ImageQuantificationToolboxUIFigure);
            app.Release10Label.Text = 'Release 1.0';
            % Show the figure after all components are created
            app.ImageQuantificationToolboxUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MEMLQuantToolboxApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageQuantificationToolboxUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageQuantificationToolboxUIFigure)
        end
    end
end