classdef CompFracApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ChannelstoCompareDropDown1      matlab.ui.control.DropDown
        ChannelstoCompareDropDownLabel  matlab.ui.control.Label
        ComponentFractionLabel          matlab.ui.control.Label
        ThresholdingOptionsButtonGroup  matlab.ui.container.ButtonGroup
        YesButton                       matlab.ui.control.RadioButton
        NoButton                        matlab.ui.control.RadioButton
        ChannelstoCompareDropDown2      matlab.ui.control.DropDown
        SubmitButton                    matlab.ui.control.Button
    end

   methods (Access = private)

        %intakes parameters necessary to accessing and processing
        %an image into two binarized channels. returns location of
        %saved binary images.
        function [fullSaveA, fullSaveB] = CompBinarize(~, yesVal, channelNum, selectedChannelA, selectedChannelB, dimension, fileName, fileImportLocation, fileSaveLocation, fileEnding, mask)
                fileToOpen = strcat(fileImportLocation, filesep, fileName);

                ij.IJ.open(fileToOpen);

                %importing mask, must use Roi.setPosition for 2D + Cryo
                if mask == 1
                    if strcmp(dimension, '3D') == 1
                        ij.IJ.run("To ROI Manager");
                    else
                        ij.IJ.runMacro("Roi.setPosition(1)");
                        ij.IJ.runMacro('roiManager("Add")');
                    end
                end

                %slicing stack
                ij.IJ.run("Stack Slicer", "split_channels stack_order=XYCZT");

                %code to remove unused windows. might be redundant but
                %could help with memory usage when windows are processed
                possibleChannels = 0:1:channelNum - 1;
                exclude = [selectedChannelA selectedChannelB];
                channels = setdiff(possibleChannels, exclude);
    
                for i=1:(channelNum - 2)
                    channelToClose = string(channels(i));
                    ij.IJ.runMacro(strcat("close('", fileName, ' - C=', channelToClose, "')"));
                end
    
                %assigning file names
                [~, fileNameRemoved, ~] = fileparts(fileName);
                newFileNameA = strcat(fileNameRemoved, fileEnding, '_C_', string(selectedChannelA));
                newFileNameB = strcat(fileNameRemoved, fileEnding, '_C_', string(selectedChannelB));

                fullSaveA = strcat(fileSaveLocation, filesep, newFileNameA);
                fullSaveB = strcat(fileSaveLocation, filesep, newFileNameB);

                if mask == 1
                    if strcmp(dimension, '3D') == 1
                        ij.IJ.runMacro('roiManager("List")');
                        ij.IJ.saveAs("Results", strcat(fullSaveA, ".csv"));
                        ij.IJ.runMacro(strcat("close('", newFileNameA, ".csv", "')"));
                        data = table2cell(readtable(strcat(fullSaveA, ".csv")));
                        delete(strcat(fullSaveA, ".csv"));
                        lastIndex = str2double(string(data(end,2)));
                    end
                end

                %loops for 2 iterations. better method?
                for i=1:2
                    if i == 1
                        selectedChannel = selectedChannelA;
                        fullSave = fullSaveA;
                    else
                        selectedChannel = selectedChannelB;
                        fullSave = fullSaveB;
                    end

                    ij.IJ.selectWindow(strcat(fileName, ' - C=', string(selectedChannel)));

                    %binarizing image
                    if yesVal == 1
                        ij.IJ.run('Auto Local Threshold');
                    else
                        if strcmp(dimension, '3D') == 1
                            ij.IJ.run("Auto Local Threshold", "method=Mean radius=30 parameter_1=-5 parameter_2=0 white stack");
                        else
                            ij.IJ.run("Auto Local Threshold", "method=Mean radius=30 parameter_1=-5 parameter_2=0 white"); %current default setting. possible presets in the future? tweaked base values?
                        end
                    end

                    %converting area in mask to NaN
                    if mask == 1
                        ij.IJ.run("32-bit");

                        if strcmp(dimension, '3D') == 0
                            ij.IJ.runMacro('roiManager("Select", 0)');
                            ij.IJ.runMacro('changeValues(0,255,NaN)');
                        else
                            for j=0:lastIndex
                                ij.IJ.runMacro(strcat('roiManager("Select", ',  string(j), ')'));
                                ij.IJ.runMacro('changeValues(0,255,NaN)');
                            end
                        end

                        if i == 2
                            ij.IJ.runMacro('close("ROI Manager")'); %prompt might interupt
                        end
                    end
        
                    ij.IJ.saveAs('Tiff', fullSave);
                end

                ij.IJ.runMacro('close("*")');
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            
            %import "cube" to pull data from workspace
            batch = evalin('base', 'batch');
            fileNum = evalin('base', 'fileNum');
            channelNum = evalin('base', 'channelNum');
            dimension = evalin('base', 'dimension'); 
            fileImportLocation = evalin('base', 'fileImportLocation');
            fileSaveLocation = evalin('base', 'fileSaveLocation');
            mask = evalin('base', 'mask');
            
            %assigning other necessary parameters
            selectedChannelA = str2double(app.ChannelstoCompareDropDown1.Value) - 1;
            selectedChannelB = str2double(app.ChannelstoCompareDropDown2.Value) - 1;
            yesVal = app.YesButton.Value;
            fileEnding = '_CompFrac';

            %close ui window
            closereq();

            resultsLocation = evalin('base', 'resultsLocation');
                results = fopen(resultsLocation,'a');
                timestamp = strjoin(string(fix(clock)),'_');
                header = strcat(timestamp, fileEnding, '_C_', string(selectedChannelA), '_/_C_', string(selectedChannelB));
                fprintf(results, '------------- %s -------------\n', header);
                fprintf(results, '%s, %s, %s, %s \n', 'fileName', 'Volume Fraction A', 'Volume Fraction B', 'A/B');

            %preping processing exclusive to single or batch images
            if batch == 1
                folder = dir(fileImportLocation);
                %https://stackoverflow.com/questions/27337514/matlab-dir-without-and
                %.DS_Store is MacOS exclusive and added by the OS to folders.
                %it must be filtered out for proper function.
                folder = folder(~ismember({folder.name},{'.','..','.DS_Store'}));
            else
                %only passed if single image is imported
                fileName = evalin('base', 'fileName');
            end
            
            %component fraction loop (does not loop for single files)
            for i=1:fileNum
                if batch == 1
                    fileName = folder(i).name;
                end

                [fullSaveA, fullSaveB] = CompBinarize(app, yesVal, channelNum, selectedChannelA, selectedChannelB, dimension, fileName, fileImportLocation, fileSaveLocation, fileEnding, mask);
              
                if strcmp(dimension, '3D') == 1
                    tiff_infoA = imfinfo(fullSaveA, 'tif');
                    tiff_infoB = imfinfo(fullSaveB, 'tif');

                    for j=1:size(tiff_infoA,1) 
                        Im0=imread(fullSaveA,'tif',j);
                        Im1(:,:,j)=im2double(Im0);
                    end
                
                    compA = Im1(:);

                    for j=1:size(tiff_infoB,1) 
                        Im0=imread(fullSaveB,'tif',j);
                        Im1(:,:,j)=im2double(Im0);
                    end

                    compB = Im1(:);

                else
                    Im0 = imread(fullSaveA,'tif');
                    Im1(:,:) = im2double(Im0);

                    compA = Im1(:);

                    Im0 = imread(fullSaveB,'tif');
                    Im1(:,:) = im2double(Im0);

                    compB = Im1(:);
                end

                %if mask == 1 the file will be 32-bit with values of 0,
                %255, or NaN. if mask == 0 the file will be 8-bit with
                %values of 0 or 1.
                if mask == 1
                    totalA = sum(compA,'omitnan')/255;
                    totalB = sum(compB,'omitnan')/255;
                else
                    totalA = sum(compA);
                    totalB = sum(compB);
                end

                VolumeFractionA = totalA/numel(compA(~isnan(compA)));
                VolumeFractionB = totalB/numel(compB(~isnan(compB)));

                CompFrac = VolumeFractionA / VolumeFractionB;

                clear Im0;
                clear Im1;
                
                fprintf(results, '%s, %f, %f, %f \n', fileName, VolumeFractionA, VolumeFractionB, CompFrac);
                ij.IJ.runMacro('close("*")');

                if batch == 0
                    uiwait(msgbox(sprintf('Component Fraction (A/B): %f', CompFrac)));
                    ij.IJ.runMacro('close("*")');
                end
            end

            %calling next element of UI
            MEMLQuantToolboxApp;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SubmitButton = uibutton(app.UIFigure, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.ChannelstoCompareDropDown2 = uidropdown(app.UIFigure);
            app.ChannelstoCompareDropDown2.Items = {' ', '1', '2', '3', '4', '5', '6'};
            app.ChannelstoCompareDropDown2.Tooltip = {'Component B'};
            app.ChannelstoCompareDropDown2.Placeholder = 'Option 2';
            app.ChannelstoCompareDropDown2.Value = ' ';
            app.ThresholdingOptionsButtonGroup = uibuttongroup(app.UIFigure);
            app.ThresholdingOptionsButtonGroup.Title = 'Thresholding Options';
            app.NoButton = uiradiobutton(app.ThresholdingOptionsButtonGroup);
            app.NoButton.Text = 'Automatic';
            app.NoButton.Value = true;
            app.YesButton = uiradiobutton(app.ThresholdingOptionsButtonGroup);
            app.YesButton.Text = 'Manual';
            app.ComponentFractionLabel = uilabel(app.UIFigure);
            app.ComponentFractionLabel.Text = 'Component Fraction';
            app.ChannelstoCompareDropDownLabel = uilabel(app.UIFigure);
            app.ChannelstoCompareDropDownLabel.Text = 'Channels to Compare';
            app.ChannelstoCompareDropDown1 = uidropdown(app.UIFigure);
            app.ChannelstoCompareDropDown1.Items = {' ', '1', '2', '3', '4', '5', '6'};
            app.ChannelstoCompareDropDown1.Tooltip = {'Component A'};
            app.ChannelstoCompareDropDown1.Placeholder = 'Option 1';
            app.ChannelstoCompareDropDown1.Value = ' ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CompFracApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end