classdef FiberDiameterApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageProcessingToolboxUIFigure  matlab.ui.Figure
        SkeletonAnalysisOptionsButtonGroup  matlab.ui.container.ButtonGroup
        YesButton_2                     matlab.ui.control.RadioButton
        NoButton_2                      matlab.ui.control.RadioButton
        ThresholdingOptionsButtonGroup  matlab.ui.container.ButtonGroup
        YesButton                       matlab.ui.control.RadioButton
        NoButton                        matlab.ui.control.RadioButton
        ChannelforAnalysisDropDown      matlab.ui.control.DropDown
        ChannelforAnalysisDropDownLabel  matlab.ui.control.Label
        FiberDiameterLabel              matlab.ui.control.Label
        SubmitButton                    matlab.ui.control.Button
    end

    
    methods (Access = private)

        %intakes parameters necessary to accessing and processing
        %an image into a binarized version. returns location of
        %saved binary image.
        function fullSave = Binarize(~, yesVal, channelNum, selectedChannel, dimension, fileName, fileImportLocation, fileSaveLocation, fileEnding, mask, nStacks)
                fileToOpen = strcat(fileImportLocation, filesep, fileName);

                ij.IJ.open(fileToOpen);

                %importing mask, must use Roi.setPosition for 2D + Cryo
                if mask == 1
                    for i=1:nStacks
                        ij.IJ.runMacro(strcat("Roi.setPosition(", string(i), ")"));
                        ij.IJ.runMacro('roiManager("Add")');
                    end
                end

                %slicing stacks and removing unnecessary windows.
                %convert to function
                if channelNum > 1
                    ij.IJ.run("Stack Slicer", "split_channels stack_order=XYCZT");

                    %code to remove unused windows. might be redundant but
                    %could help with memory usage when windows are processed
                    possibleChannels = 0:1:channelNum - 1;
                    exclude = selectedChannel;
                    channels = setdiff(possibleChannels, exclude);
    
                    for i=1:(channelNum - 1)
                        channelToClose = string(channels(i));
                        ij.IJ.runMacro(strcat("close('", fileName, ' - C=', channelToClose, "')"));
                    end

                    ij.IJ.selectWindow(strcat(fileName, ' - C=', string(selectedChannel)));
                end
    
                [~, fileNameRemoved, ~] = fileparts(fileName);
                newFileName = strcat(fileNameRemoved, fileEnding, '_C_', string(selectedChannel));

                fullSave = strcat(fileSaveLocation, filesep, newFileName);
    
                %binarizing image
                if yesVal == 1
                    ij.IJ.run('Auto Local Threshold');
                else
                    if strcmp(dimension, '3D') == 1
                        ij.IJ.run("Auto Local Threshold", "method=Mean radius=30 parameter_1=-5 parameter_2=0 white stack");
                    else
                        ij.IJ.run("Auto Local Threshold", "method=Mean radius=30 parameter_1=-5 parameter_2=0 white"); %current default setting. possible presets in the future? tweaked base values?
                    end
                end

                %sets value of areas inside of mask to NaN
                if mask == 1
                    ij.IJ.run("32-bit");

                    if strcmp(dimension, '3D') == 0
                        ij.IJ.runMacro('roiManager("Select", 0)');
                        ij.IJ.runMacro('changeValues(0,255,NaN)');
                        ij.IJ.runMacro('close("ROI Manager")');
                    else
                        ij.IJ.runMacro('roiManager("List")');
                        ij.IJ.saveAs("Results", strcat(fullSave, ".csv"));
                        ij.IJ.runMacro(strcat("close('", newFileName, ".csv", "')")); 
                        data = table2cell(readtable(strcat(fullSave, ".csv")));
                        delete(strcat(fullSave, ".csv"));
                        lastIndex = str2double(string(data(end,2)));
                        for j=0:lastIndex
                            ij.IJ.runMacro(strcat('roiManager("Select", ',  string(j), ')'));
                            ij.IJ.runMacro('changeValues(0,255,NaN)');
                        end
                        ij.IJ.runMacro('close("ROI Manager")'); %prompt might interupt
                    end
                end
    
                ij.IJ.saveAs('Tiff', fullSave);
                %ij.IJ.runMacro('close("*")'); %commented out to avoid
                %needing to reopeneing binary image
        end

        function skeletonLength = skeletonizeNRead(~, fileName, fileSaveLocation, fileEndingSkel, fileEndingData, options)
            ij.IJ.run("Skeletonize (2D/3D)");

            if options == 0
                ij.IJ.run("Analyze Skeleton (2D/3D)", "prune=none");
            else
                ij.IJ.run("Analyze Skeleton (2D/3D)");
            end

            %create folder for raw data. created only when needed to avoid
            %clutter on sessions which do not store raw data.
            rawData = strcat(fileSaveLocation, filesep, 'Raw_Data');
            if ~exist(rawData, 'dir')
                mkdir(rawData);
            end

            fullSaveA = strcat(rawData, filesep, fileName, fileEndingData);
            fullSaveB = strcat(fileSaveLocation, filesep, fileName, fileEndingSkel);

            ij.IJ.saveAs("Results", fullSaveA);
            ij.IJ.run("Close");
            ij.IJ.saveAs("tiff", fullSaveB);

            %importing only branch num and avg length for skeleton length
            %calculations
            opts = detectImportOptions(fullSaveA);
            opts.SelectedVariableNames = ["x_Branches", "AverageBranchLength"];
            data=readtable(fullSaveA, opts);

            branchNum = table2array(data(1:end, 1))
            avgBranchLength = table2array(data(1:end, 2))

            skeletonLength = sum(branchNum .* avgBranchLength);
            assignin('base', 'skeletonLength', skeletonLength)
        end

        %set up for converting from pixel to micron using image metadata. 
        %if X and Y resolutions do not agree, no conversion occurs due to
        %theoretical pixel mismatch.
        function [Unit, Value] = UnitConvert(~, image)
            imageInfo = imfinfo(image, 'tif');
            xRes = imageInfo.XResolution; %added for 3d values as they create an array of resolutions for each slice. this collects the resolution of the first slice only
            yRes = imageInfo.YResolution;
            if round(xRes, 4) == round(yRes, 4)
                Unit = 'micron';
                Value = imageInfo.XResolution;
            else
                Unit = 'pixel';
                Value = 1;
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)

            %import "cube" to pull data from workspace
            batch = evalin('base', 'batch');
            loop = evalin('base', 'loop');
            channelNum = evalin('base', 'channelNum');
            dimension = evalin('base', 'dimension'); 
            fileImportLocation = evalin('base', 'unprocessedLocation');
            fileSaveLocation = evalin('base', 'fileSaveLocation');
            mask = evalin('base', 'mask');
            nStacks = evalin('base', 'nStacks');

            yesVal = app.YesButton.Value;
            yesVal2 = app.YesButton_2.Value;
            selectedChannel = app.ChannelforAnalysisDropDown.Value;

            closereq();

            fileEndingA = '_FibDiamBinary';
            fileEndingB = '_FibDiamSkeleton.tif';
            fileEndingC = '_FibDiamSkelData.csv';

            %opening results.csv and printing header
            resultsLocation = evalin('base', 'resultsLocation');
            results = fopen(resultsLocation,'a'); %appending
            timestamp = strjoin(string(fix(clock)),'_');
            header = strcat(timestamp, 'fibDiam_C_', string(selectedChannel));
            fprintf(results, '-------------%s-------------\n', header);
            fprintf(results, '%s, %s, %s \n', 'fileName', 'averageDiameter', 'units');

            %preping processing exclusive to single or batch images
            if batch == 1
                folder = dir(fileImportLocation);
                %https://stackoverflow.com/questions/27337514/matlab-dir-without-and
                %.DS_Store is MacOS exclusive and added by the OS to folders.
                %it must be filtered out for proper function.
                folder = folder(~ismember({folder.name},{'.','..','.DS_Store'}));
            else
                fileName = evalin('base', 'fileName');
            end

            %create folder for raw data. created only when needed to avoid
            %clutter on sessions which do not store raw data.
            rawData = strcat(fileSaveLocation, filesep, 'Raw_Data');
            if ~exist(rawData, 'dir')
                mkdir(rawData);
            end

            for i=1:loop
                if batch == 1
                    fileName = folder(i).name;
                end

                fullSave = strcat(fileImportLocation, filesep, fileName);

                [unit, Resolution] = UnitConvert(app, fullSave);

                binaryLocation = Binarize(app, yesVal, channelNum, selectedChannel, dimension, fileName, fileImportLocation, fileSaveLocation, fileEndingA, mask, nStacks);

                skeletonLength = skeletonizeNRead(app, fileName, fileSaveLocation, fileEndingB, fileEndingC, yesVal2);

                skeletonLengthConverted = skeletonLength / Resolution;

                %reading info from tif and converting to array.
                %maybe try to IJM.getImageAs
                if strcmp(dimension, '3D') == 1
                    tiff_info = imfinfo(binaryLocation, 'tif');

                    for j=1:size(tiff_info,1) 
                        Im0=imread(binaryLocation,'tif',j);
                        Im1(:,:,j)=im2double(Im0);
                    end
                else
                    Im0 = imread(binaryLocation,'tif');
                    Im1(:,:) = im2double(Im0);
                end

                %if mask == 1 the file will be 32-bit with values of 0,
                %255, or NaN. if mask == 0 the file will be 8-bit with
                %values of 0 or 1.
                if mask == 1
                    total = sum(Im1(:),'omitnan')/255;
                else
                    %checked. div by 255 not necessary
                    total = sum(Im1(:));
                end
                
                clear Im0;
                clear Im1;

                totalConverted = total / (Resolution ^2);

                totalConverted

                skeletonLength

                skeletonLengthConverted

                thing = total / skeletonLength

                avgDiam = totalConverted / skeletonLengthConverted;

                %printing results
                fprintf(results, '%s, %f, %s \n', fileName, avgDiam, unit);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImageProcessingToolboxUIFigure = uifigure('Visible', 'off');
            app.ImageProcessingToolboxUIFigure.Name = 'Image Processing Toolbox';
            app.SubmitButton = uibutton(app.ImageProcessingToolboxUIFigure, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.FiberDiameterLabel = uilabel(app.ImageProcessingToolboxUIFigure);
            app.FiberDiameterLabel.Text = 'Fiber Diameter';
            app.ChannelforAnalysisDropDownLabel = uilabel(app.ImageProcessingToolboxUIFigure);
            app.ChannelforAnalysisDropDownLabel.Text = 'Channel for Analysis';
            app.ChannelforAnalysisDropDown = uidropdown(app.ImageProcessingToolboxUIFigure);
            app.ChannelforAnalysisDropDown.Items = {' ', '1', '2', '3', '4', '5', '6'};
            app.ChannelforAnalysisDropDown.Value = ' ';
            app.ThresholdingOptionsButtonGroup = uibuttongroup(app.ImageProcessingToolboxUIFigure);
            app.ThresholdingOptionsButtonGroup.Title = 'Thresholding Options';
            app.NoButton = uiradiobutton(app.ThresholdingOptionsButtonGroup);
            app.NoButton.Text = 'Automatic';
            app.NoButton.Value = true;
            app.YesButton = uiradiobutton(app.ThresholdingOptionsButtonGroup);
            app.YesButton.Text = 'Manual';
            app.SkeletonAnalysisOptionsButtonGroup = uibuttongroup(app.ImageProcessingToolboxUIFigure);
            app.SkeletonAnalysisOptionsButtonGroup.Title = 'Skeleton Analysis Options';
            app.NoButton_2 = uiradiobutton(app.SkeletonAnalysisOptionsButtonGroup);
            app.NoButton_2.Text = 'Automatic';
            app.NoButton_2.Value = true;
            app.YesButton_2 = uiradiobutton(app.SkeletonAnalysisOptionsButtonGroup);
            app.YesButton_2.Text = 'Manual';
            % Show the figure after all components are created
            app.ImageProcessingToolboxUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FiberDiameterApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageProcessingToolboxUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageProcessingToolboxUIFigure)
        end
    end
end