classdef ViewerApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ChannelsButtonGroup   matlab.ui.container.ButtonGroup
        CombineButton         matlab.ui.control.RadioButton
        SingleButton          matlab.ui.control.RadioButton
        SubmitButton          matlab.ui.control.Button
        DViewerLabel          matlab.ui.control.Label
        ViewerButtonGroup     matlab.ui.container.ButtonGroup
        ClearVolumeButton     matlab.ui.control.RadioButton
        ImageJ3DViewerButton  matlab.ui.control.RadioButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            %import "cube" to pull data from workspace
            batch = evalin('base', 'batch');
            fileNum = evalin('base', 'fileNum');
            channelNum = evalin('base', 'channelNum');
            dimension = evalin('base', 'dimension'); 
            fileImportLocation = evalin('base', 'fileImportLocation');
            mask = evalin('base', 'mask');
            nStacks = evalin('base', 'nStacks');

            selectedViewer = app.ImageJ3DViewerButton.Value;
            channels = app.SingleButton.Value;

            closereq;

            %prompting user to select a selected channel if a single
            %channel is wanted. checks if value is valid
            if channels == 1
                selectedChannel = str2double(inputdlg('Enter the channel you would like to select:')) - 1;
                if (selectedChannel < channelNum && selectedChannel >= 0) == 0
                    msgbox('Please enter a valid channel number.', 'Error', 'error');
                    ij.IJ.runMacro('close("*")');
                    ViewerApp;
                    return
                end
            end

            if batch == 1
                folder = dir(fileImportLocation);
                %https://stackoverflow.com/questions/27337514/matlab-dir-without-and
                %.DS_Store is MacOS exclusive and added by the OS to folders.
                %it must be filtered out for proper function.
                folder = folder(~ismember({folder.name},{'.','..','.DS_Store'}));
            else
                %only passed if single image is imported
                fileName = evalin('base', 'fileName');
            end
            
            %volume fraction loop (does not loop for single files)
            for i=1:fileNum
                if batch == 1
                    fileName = folder(i).name;
                end
    
                fileToOpen = strcat(fileImportLocation, filesep, fileName);
    
                ij.IJ.open(fileToOpen);
    
                %importing mask, must use Roi.setPosition for 2D + Cryo
                if mask == 1
                    for j=1:nStacks
                        ij.IJ.runMacro(strcat("Roi.setPosition(", string(j), ")"));
                        ij.IJ.runMacro('roiManager("Add")');
                    end
                end
    
                if channels == 1
                    %slicing stacks and removing unnecessary windows
                    if channelNum > 1
                        ij.IJ.run("Stack Slicer", "split_channels stack_order=XYCZT");
        
                        %code to remove unused windows. might be redundant but
                        %could help with memory usage when windows are processed
                        possibleChannels = 0:1:channelNum - 1;
                        exclude = selectedChannel;
                        channels = setdiff(possibleChannels, exclude);
        
                        for j=1:(channelNum - 1)
                            channelToClose = string(channels(j));
                            ij.IJ.runMacro(strcat("close('", fileName, ' - C=', channelToClose, "')"));
                        end
        
                        fileNameWChan = strcat(fileName, ' - C=', string(selectedChannel));
                        ij.IJ.selectWindow(fileNameWChan);
                        fileName = fileNameWChan;
                    end
                else
                    %preliminary testing suggests this works with current
                    %roi system
                    if selectedViewer == 1
                        ij.IJ.run("Merge Channels...", strcat('" c1=[', fileName, '] c2=[', fileName, '] c3=[', fileName, '] c4=[', fileName, '] c5=[', fileName, '] c6=[', fileName, '] c7=[', fileName, ']"'));
                    end
                end

                %sets value of areas inside of mask to 0
                if mask == 1
                    ij.IJ.run("32-bit");
    
                    if strcmp(dimension, '3D') == 0
                        ij.IJ.runMacro('roiManager("Select", 0)');
                        ij.IJ.runMacro('changeValues(0,255,0)');
                        ij.IJ.runMacro('close("ROI Manager")');
                    else
                        ij.IJ.runMacro('roiManager("List")');
                        ij.IJ.saveAs("Results", strcat(fullSave, ".csv"));
                        ij.IJ.runMacro(strcat("close('", newFileName, ".csv", "')")); 
                        data = table2cell(readtable(strcat(fullSave, ".csv")));
                        delete(strcat(fullSave, ".csv"));
                        lastIndex = str2double(string(data(end,2)));
                        for j=0:lastIndex
                            ij.IJ.runMacro(strcat('roiManager("Select", ',  string(j), ')'));
                            ij.IJ.runMacro('changeValues(0,255,0)');
                        end
                        ij.IJ.runMacro('close("ROI Manager")'); %prompt might interupt
                    end
                end

                if selectedViewer == 1
                    ij.IJ.run("3D Viewer");
                    ij.IJ.runMacro('call("ij3d.ImageJ3DViewer.setCoordinateSystem", "false")'); %needed. causes java to throw errors without
                    ij.IJ.runMacro(strcat('call("ij3d.ImageJ3DViewer.add", "', fileName, '", "None", "', fileName, '", "0", "true", "true", "true", "1", "0")'));
                    clc
                else
                    ij.IJ.run("Open in ClearVolume", "datasetview=net.imagej.display.DefaultDatasetView@d3d0f6b");
                end

                MEMLQuantToolboxApp;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ViewerButtonGroup = uibuttongroup(app.UIFigure);
            app.ViewerButtonGroup.Title = 'Viewer';
            app.ImageJ3DViewerButton = uiradiobutton(app.ViewerButtonGroup);
            app.ImageJ3DViewerButton.Text = 'ImageJ 3D Viewer';
            app.ImageJ3DViewerButton.Value = true;
            app.ClearVolumeButton = uiradiobutton(app.ViewerButtonGroup);
            app.ClearVolumeButton.Text = 'ClearVolume';
            app.DViewerLabel = uilabel(app.UIFigure);
            app.DViewerLabel.Text = '3D Viewer';
            app.SubmitButton = uibutton(app.UIFigure, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.ChannelsButtonGroup = uibuttongroup(app.UIFigure);
            app.ChannelsButtonGroup.Title = 'Channels';
            app.SingleButton = uiradiobutton(app.ChannelsButtonGroup);
            app.SingleButton.Text = 'Single';
            app.SingleButton.Value = true;
            app.CombineButton = uiradiobutton(app.ChannelsButtonGroup);
            app.CombineButton.Text = 'Combine';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ViewerApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end