classdef VolFracApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CustomImportButton              matlab.ui.control.CheckBox
        ThresholdingOptionsButtonGroup  matlab.ui.container.ButtonGroup
        Image                           matlab.ui.control.Image
        YesButton                       matlab.ui.control.RadioButton
        NoButton                        matlab.ui.control.RadioButton
        ChannelforAnalysisDropDown      matlab.ui.control.DropDown
        ChannelforAnalysisDropDownLabel  matlab.ui.control.Label
        VolumeFractionLabel             matlab.ui.control.Label
        SubmitButton                    matlab.ui.control.Button
    end

    methods (Access = private)

        function [batch, batchNum, channelNum, dimension, fileName, fileSaveLocation, homePath, fileNum, mask, nStacks, resultsLocation, sessionNum, fileImportLocation] = WorkspaceImporter(~)
                batch = evalin('base', 'batch'); %(logical) 0 for single file, 1 for batch
                batchNum = evalin('base', 'batchNum'); %(num) subsession number (i.e sessionNum = X, batchNum = Y for session X, file/batch Y)
                channelNum = evalin('base', 'channelNum'); %(num) number of channels present in the image
                dimension = evalin('base', 'dimension'); %(string) '2D' for 2d images, 'Cryo' for cryosection images, '3D' for 3d images
                fileName = evalin('base', 'fileName'); %(string) for single files, fileName contains the file name. for batches, contains 'N/A' and fileNames must be harvested in a loop (built into the 'Toolbox Hub' function below)
                fileSaveLocation = evalin('base', 'fileSaveLocation'); %(string) file path for the 'Processed_Images' folder. this is where images used in modules should be saved.
                homePath = evalin('base', 'homePath'); %(string) file path for the subsession folder. this is what contains the 'Source_Images' and 'Processed_Images' folders in addition to results.csv and metadata.txt
                fileNum = evalin('base', 'fileNum'); %(num) number of files being imported (1 for single, X for batch)
                mask = evalin('base', 'mask'); %(logical) 0 for no mask, 1 for mask (positive and negative masks are overlayed in the same way)
                nStacks = evalin('base', 'nStacks'); %(num) number of z-slices (only non-one for 3d images)
                resultsLocation = evalin('base', 'resultsLocation'); %(string) file path for Results.csv
                sessionNum = evalin('base', 'sessionNum'); %(num) session number (i.e sessionNum = X, batchNum = Y for session X, file/batch Y)
                fileImportLocation = evalin('base', 'fileImportLocation'); %(string) file path for the 'Source_Images' folder. this is where freshly imported images are placed. use this as the source for pulling images
            end

        function results = resultsSetup(~, resultsLocation, fileEnding, selectedChannel)
            results = fopen(resultsLocation,'a');
            timestamp = strjoin(string(fix(clock)),'_');
            header = strcat(timestamp, fileEnding, '_C_', string(selectedChannel));
            fprintf(results, '------------- %s -------------\n', header);
            fprintf(results, '%s, %s \n', 'fileName', 'volFrac'); %modify me!
        end

        function maskImporter(~, mask, dimension)
            if mask == 1
                if strcmp(dimension, '3D') == 1
                    ij.IJ.run("To ROI Manager");
                else
                    ij.IJ.runMacro("Roi.setPosition(1)");
                    ij.IJ.runMacro('roiManager("Add")');
                end
            end
        end

        function sliceSelector(~, channelNum, selectedChannel, fileName)
            if channelNum > 1
                ij.IJ.run("Stack Slicer", "split_channels stack_order=XYCZT");

                possibleChannels = 0:1:channelNum - 1;
                exclude = selectedChannel;
                channels = setdiff(possibleChannels, exclude);

                for i=1:(channelNum - 1)
                    channelToClose = string(channels(i));
                    ij.IJ.runMacro(strcat("close('", fileName, ' - C=', channelToClose, "')"));
                end

                ij.IJ.selectWindow(strcat(fileName, ' - C=', string(selectedChannel)));
            end
        end

        function maskApplicator(~, value, mask, dimension, fileSaveLocation, newFileName)  
            if mask == 1
                if isnan(value)
                    ij.IJ.run("32-bit");
                end

                if strcmp(dimension, '3D') == 0
                    ij.IJ.runMacro('roiManager("Select", 0)');
                    ij.IJ.runMacro(strcat('changeValues(0,255,', num2str(value), ')'));
                    ij.IJ.runMacro('close("ROI Manager")');
                else
                    fullName = strcat(fileSaveLocation, filesep, newFileName, ".csv");
                    ij.IJ.runMacro('roiManager("List")');
                    ij.IJ.saveAs("Results", fullName);
                    ij.IJ.runMacro(strcat("close('", newFileName, ".csv", "')")); 
                    data = table2cell(readtable(fullName));
                    delete(fullName);
                    lastIndex = str2double(string(data(end,2)));
                    for j=0:lastIndex
                        ij.IJ.runMacro(strcat('roiManager("Select", ',  string(j), ')'));
                        ij.IJ.runMacro(strcat('changeValues(0,255,', num2str(value), ')'));
                    end
                    ij.IJ.runMacro('close("ROI Manager")');
                end
            end
        end

        function resultsPrinter(~, batch, results, fileName, output)
            if batch == 0
                uiwait(msgbox(sprintf('Volume Fraction: %f', output)));
            end

            fprintf(results, '%s, %f \n', fileName, output);
        end

        %intakes parameters necessary to accessing and processing
        %an image into a binarized version. returns location of
        %saved binary image.
        function fullSave = Binarizer(app, yesVal, channelNum, selectedChannel, dimension, fileName, fileImportLocation, fileSaveLocation, fileEnding, mask)
            fileToOpen = strcat(fileImportLocation, filesep, fileName);

            ij.IJ.open(fileToOpen);

            %importing mask
            maskImporter(app, mask, dimension);

            %selecting channel
            sliceSelector(app, channelNum, selectedChannel, fileName);

            [~, fileNameRemoved, ~] = fileparts(fileName);
            newFileName = strcat(fileNameRemoved, fileEnding, '_C_', string(selectedChannel));

            fullSave = strcat(fileSaveLocation, filesep, newFileName);

            %removing extraneous areas before thresholding
            %maskApplicator(app, 0, mask, dimension, fileSaveLocation, newFileName);

            %binarizing image
            if yesVal == 1
                ij.IJ.run('Auto Local Threshold');
            else
                if strcmp(dimension, '3D') == 1
                    ij.IJ.run("Auto Local Threshold", "method=Mean radius=30 parameter_1=-5 parameter_2=0 white stack");
                else
                    ij.IJ.run("Auto Local Threshold", "method=Mean radius=30 parameter_1=-5 parameter_2=0 white");
                end
            end

            %setting value of areas inside of mask to NaN
            maskApplicator(app, NaN, mask, dimension, fileSaveLocation, newFileName);

            %saving binary image and closing window
            ij.IJ.run("Select None");
            ij.IJ.saveAs('Tiff', fullSave);
            ij.IJ.runMacro('close("*")');
        end

        %{
        function fullSave = Binarize(~, yesVal, channelNum, selectedChannel, dimension, fileName, fileImportLocation, fileSaveLocation, fileEnding, mask, nStacks)
                fileToOpen = strcat(fileImportLocation, filesep, fileName);

                ij.IJ.open(fileToOpen);

                %importing mask, must use Roi.setPosition for 2D + Cryo
                if mask == 1
                    if strcmp(dimension, '3D') == 1
                        ij.IJ.run("To ROI Manager");
                    else
                        ij.IJ.runMacro("Roi.setPosition(1)");
                        ij.IJ.runMacro('roiManager("Add")');
                    end
                end

                %slicing stacks and removing unnecessary windows
                if channelNum > 1
                    ij.IJ.run("Stack Slicer", "split_channels stack_order=XYCZT");

                    %code to remove unused windows. might be redundant but
                    %could help with memory usage when windows are processed
                    possibleChannels = 0:1:channelNum - 1;
                    exclude = selectedChannel;
                    channels = setdiff(possibleChannels, exclude);
    
                    for i=1:(channelNum - 1)
                        channelToClose = string(channels(i));
                        ij.IJ.runMacro(strcat("close('", fileName, ' - C=', channelToClose, "')"));
                    end

                    ij.IJ.selectWindow(strcat(fileName, ' - C=', string(selectedChannel)));
                end
    
                [~, fileNameRemoved, ~] = fileparts(fileName);
                newFileName = strcat(fileNameRemoved, fileEnding, '_C_', string(selectedChannel));

                fullSave = strcat(fileSaveLocation, filesep, newFileName);
    
                %binarizing image
                if yesVal == 1
                    ij.IJ.run('Auto Local Threshold');
                else
                    if strcmp(dimension, '3D') == 1
                        ij.IJ.run("Auto Local Threshold", "method=Mean radius=30 parameter_1=-5 parameter_2=0 white stack");
                    else
                        ij.IJ.run("Auto Local Threshold", "method=Mean radius=30 parameter_1=-5 parameter_2=0 white"); %current default setting. possible presets in the future? tweaked base values?
                    end
                end

                %sets value of areas inside of mask to NaN
                if mask == 1
                    ij.IJ.run("32-bit");

                    if strcmp(dimension, '3D') == 0
                        ij.IJ.runMacro('roiManager("Select", 0)');
                        ij.IJ.runMacro('changeValues(0,255,NaN)');
                        ij.IJ.runMacro('close("ROI Manager")');
                    else
                        ij.IJ.runMacro('roiManager("List")');
                        ij.IJ.saveAs("Results", strcat(fullSave, ".csv"));
                        ij.IJ.runMacro(strcat("close('", newFileName, ".csv", "')")); 
                        data = table2cell(readtable(strcat(fullSave, ".csv")));
                        delete(strcat(fullSave, ".csv"));
                        lastIndex = str2double(string(data(end,2)));
                        for j=0:lastIndex
                            ij.IJ.runMacro(strcat('roiManager("Select", ',  string(j), ')'));
                            ij.IJ.runMacro('changeValues(0,255,NaN)');
                        end
                        ij.IJ.runMacro('close("ROI Manager")'); %prompt might interupt
                    end
                end
    
                ij.IJ.saveAs('Tiff', fullSave);
                ij.IJ.runMacro('close("*")');
        end
        %}
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            
            %import "cube" to pull data from workspace
            [batch, batchNum, channelNum, dimension, fileName, fileSaveLocation, homePath, fileNum, mask, nStacks, resultsLocation, sessionNum, fileImportLocation] = WorkspaceImporter(app);

            %assigning other necessary parameters
            selectedChannel = str2double(app.ChannelforAnalysisDropDown.Value) - 1;
            yesVal = app.YesButton.Value;
            customImport = app.CustomImportButton.Value;
            fileEnding = '_VolFrac';

            %close ui window
            closereq();

            %preping processing exclusive to single or batch images
            results = resultsSetup(app, resultsLocation, fileEnding, selectedChannel);

            if batch == 1
                folder = dir(fileImportLocation);
                %https://stackoverflow.com/questions/27337514/matlab-dir-without-and
                %.DS_Store is MacOS exclusive and added by the OS to folders.
                %it must be filtered out for proper function.
                folder = folder(~ismember({folder.name},{'.','..','.DS_Store'}));

            else
                %only passed if single image is imported
                fileName = evalin('base', 'fileName');
            end
            
            %volume fraction loop (does not loop for single files)
            for i=1:fileNum
                if batch == 1
                    fileName = folder(i).name;
                end

                if customImport == 1
                    fullSave = string(ij.IJ.getFilePath(''));
                else
                    fullSave = Binarizer(app, yesVal, channelNum, selectedChannel, dimension, fileName, fileImportLocation, fileSaveLocation, fileEnding, mask);
                end
                
                %reading info from tif and converting to array.
                %maybe try to IJM.getImageAs
                if strcmp(dimension, '3D') == 1
                    tiff_info = imfinfo(fullSave, 'tif');

                    for j=1:size(tiff_info,1) 
                        Im0=imread(fullSave,'tif',j);
                        Im1(:,:,j)=im2double(Im0);
                    end
                else
                    Im0 = imread(fullSave,'tif');
                    Im1(:,:) = im2double(Im0);
                end

                %if mask == 1 the file will be 32-bit with values of 0,
                %255, or NaN. if mask == 0 the file will be 8-bit with
                %values of 0 or 1.
                if mask == 1
                    total = sum(Im1(:),'omitnan')/255;
                else
                    %checked. div by 255 not necessary
                    total = sum(Im1(:));
                end

                VolumeFraction = total/numel(Im1(~isnan(Im1)));
                
                clear Im0;
                clear Im1;
                
                %printing results
                resultsPrinter(app, batch, results, fileName, VolumeFraction);
            end

            %calling next element of UI
            MEMLQuantToolboxApp;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SubmitButton = uibutton(app.UIFigure, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.VolumeFractionLabel = uilabel(app.UIFigure);
            app.VolumeFractionLabel.Text = 'Volume Fraction';
            app.ChannelforAnalysisDropDownLabel = uilabel(app.UIFigure);
            app.ChannelforAnalysisDropDownLabel.Text = 'Channel for Analysis';
            app.ChannelforAnalysisDropDown = uidropdown(app.UIFigure);
            app.ChannelforAnalysisDropDown.Items = {' ', '1', '2', '3', '4', '5', '6'};
            app.ChannelforAnalysisDropDown.Value = ' ';
            app.ThresholdingOptionsButtonGroup = uibuttongroup(app.UIFigure);
            app.ThresholdingOptionsButtonGroup.Title = 'Thresholding Options';
            app.NoButton = uiradiobutton(app.ThresholdingOptionsButtonGroup);
            app.NoButton.Text = 'Automatic';
            app.NoButton.Value = true;
            app.YesButton = uiradiobutton(app.ThresholdingOptionsButtonGroup);
            app.YesButton.Text = 'Manual';
            app.Image = uiimage(app.ThresholdingOptionsButtonGroup);
            app.Image.Tooltip = {'Automatic - Uses ImageJ Auto Local Thresholding with the settings of "method=Mean radius=30 parameter_1=-5 parameter_2=0"'; ''; 'Manual - Opens the ImageJ Auto Local Thresholding options window.'};
            app.Image.ImageSource = 'image.png';
            app.CustomImportButton = uicheckbox(app.UIFigure);
            app.CustomImportButton.Text = 'Import Image?';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VolFracApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end